classdef WidefieldImager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainPanel                      matlab.ui.Figure
        PassiveControlsPanel           matlab.ui.container.Panel
        PassiveControlsGrid            matlab.ui.container.GridLayout
        TrialTimeout                   matlab.ui.control.Spinner
        TrialTimeoutsSpinnerLabel      matlab.ui.control.Label
        ImagesPerTrial                 matlab.ui.control.Spinner
        ImagesperTrialSpinnerLabel     matlab.ui.control.Label
        TogglePassiveAcquisition       matlab.ui.control.StateButton
        ModeSelectPanel                matlab.ui.container.Panel
        ModeSelectGrid                 matlab.ui.container.GridLayout
        CaptureMode                    matlab.ui.control.DropDown
        CaptureModeDropDownLabel       matlab.ui.control.Label
        ManualAcquisitionControlPanel  matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        ResetROIButton                 matlab.ui.control.Button
        DrawROIButton                  matlab.ui.control.Button
        StopTrigger                    matlab.ui.control.Button
        TrialTrigger                   matlab.ui.control.Button
        DisplayPanel                   matlab.ui.container.Panel
        DisplayPanelGrid               matlab.ui.container.GridLayout
        ControlsPanel                  matlab.ui.container.Panel
        ControlsGrid                   matlab.ui.container.GridLayout
        StimFilePath                   matlab.ui.control.EditField
        LoadStimButton                 matlab.ui.control.Button
        TriggerLastSent                matlab.ui.control.Label
        LastTriggerLabel               matlab.ui.control.Label
        StartwithStimulusButton        matlab.ui.control.Button
        CameraTriggerMode              matlab.ui.control.DropDown
        CamModeLabel_2                 matlab.ui.control.Label
        LightMode                      matlab.ui.control.DropDown
        LightLabel_2                   matlab.ui.control.Label
        SendTrigger                    matlab.ui.control.Button
        TakeSnapshotButton             matlab.ui.control.Button
        PreviewButton                  matlab.ui.control.StateButton
        Slider                         matlab.ui.control.Slider
        StatusPanel                    matlab.ui.container.Panel
        StatusGrid                     matlab.ui.container.GridLayout
        LoadingIndicatorLamp           matlab.ui.control.Lamp
        LoadingCompletedLampLabel      matlab.ui.control.Label
        AcquiredCounter                matlab.ui.control.Label
        LoggingTimer                   matlab.ui.control.Label
        AcquiringTimer                 matlab.ui.control.Label
        ReadyTimer                     matlab.ui.control.Label
        LoggingLamp                    matlab.ui.control.Lamp
        LoggingLabel                   matlab.ui.control.Label
        ResetCameraButton              matlab.ui.control.Button
        AcquiringLamp                  matlab.ui.control.Lamp
        AcquiringLampLabel             matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        ReadyLampLabel                 matlab.ui.control.Label
        PreviouslyCapturedTime         matlab.ui.control.Label
        AcquiredLabel                  matlab.ui.control.Label
        VerboseModeButton              matlab.ui.control.StateButton
        StimulusDisplay                matlab.ui.control.UIAxes
        ImagePlot                      matlab.ui.control.UIAxes
        FluorescenceTrace              matlab.ui.control.UIAxes
        CameraPropertiesPanel          matlab.ui.container.Panel
        CameraPropertiesGrid           matlab.ui.container.GridLayout
        CameraROI                      matlab.ui.control.EditField
        CameraROIEditFieldLabel        matlab.ui.control.Label
        ResetCameraROI                 matlab.ui.control.Button
        SelectCameraROI                matlab.ui.control.Button
        BinningDropDown                matlab.ui.control.DropDown
        BinningDropDownLabel           matlab.ui.control.Label
        CurrentResolutionLabel         matlab.ui.control.Label
        CurrentResolution              matlab.ui.control.Label
        GaindB                         matlab.ui.control.Spinner
        GaindBSpinnerLabel             matlab.ui.control.Label
        ExposureTime                   matlab.ui.control.NumericEditField
        ExposureLabel                  matlab.ui.control.Label
        DataStoragePanel               matlab.ui.container.Panel
        DataStorageGrid                matlab.ui.container.GridLayout
        ViewPreviousButton             matlab.ui.control.StateButton
        WaitingTime                    matlab.ui.control.NumericEditField
        PostStimLabel_2                matlab.ui.control.Label
        PostStimFrames                 matlab.ui.control.NumericEditField
        PostStimLabel                  matlab.ui.control.Label
        BaselineFrames                 matlab.ui.control.NumericEditField
        BaselinesLabel                 matlab.ui.control.Label
        DriveSelect                    matlab.ui.control.DropDown
        DriveSelectDropDownLabel       matlab.ui.control.Label
        DataPath                       matlab.ui.control.TextArea
        FilePathTextAreaLabel          matlab.ui.control.Label
        PreviousTrials                 matlab.ui.control.ListBox
        ExperimentInformationPanel     matlab.ui.container.Panel
        ExperimentInfoGrid             matlab.ui.container.GridLayout
        ConfigFilePath                 matlab.ui.control.EditField
        LoadConfigButton               matlab.ui.control.Button
        DebugButton                    matlab.ui.control.Button
        DaqName                        matlab.ui.control.DropDown
        DAQLabel                       matlab.ui.control.Label
        NewTrialButton                 matlab.ui.control.Button
        ExperimentType                 matlab.ui.control.DropDown
        ExperimentDropDownLabel        matlab.ui.control.Label
        AnimalID                       matlab.ui.control.DropDown
        AnimalIDDropDownLabel          matlab.ui.control.Label
        TrialNr                        matlab.ui.control.Label
        RecordingID                    matlab.ui.control.Label
        TrialNrLabel                   matlab.ui.control.Label
        RecordingIDLabel               matlab.ui.control.Label
        NewExperimentButton            matlab.ui.control.Button
        NewAnimalButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        extraFrames % amount of frames where the light is switched off at the end of the recording. This helps to ensure proper alignment with analog data.
        minSize % minimum free disk space before producing a warning (in GB)
        serverPath % data server path
        aNIdevice % analog handle for daq device
        dNIdevice % digital handle for daq device
        vidName % handle for video name
        vidObj % handle for video device
        calibration % Placeholder for calibration mode TODO DO I NEED THIS
        ROIposition % Position of the ROI

        % Path variables - adapted from handles.path which had attributes base, AnimalID, ExpType, RecordingID, 
        pathBase
        pathExpType
        pathAnimalID
        pathRecordingID

        SnapshotTaken % flag to say whether initialisation snapshot has been taken. Recording won't start if it's false
        
        fluorescenceTraceLine % animated lines for fluorescence trace
        stimulusTraceLine % animated line for stimulus trace
        currentFrameCount % number of frames in current trial

        % viewing previous trials
        TrialsPath % path for current animal trials
        CachedImages % images in cache for faster access
        CurrentImageIndex % index of currently displayed image in file system
        ImageIndexOffset % index of currently displayed image in images array
        
        ROIRectangle % rectangle to display the selected ROI
        errorState % boolean to check if something's gone wrong. TBI
        GuiTimer % Timer for scheduling GUI updates
        ReadyTic % tic for amount of time camera has been ready
        AcquiringTic % tic for amount of time camera has been acquiring
        LoggingTic % tic for amount of time camera has been logging
        LastAcquisitionTic % tic for amount of time since last image acquired
        ImageWriteThread % Asynchronous thread for image saving to avoid blocking behaviour in stimulus
        VerboseModeStartedTic % tic for tracking when verbose mode started
        
        ActiveParams = struct() % parameters for active acquisition.
    end
    
    methods (Access = private)

        function vidObj = CheckCamera(app)
            % check videoadapter, set settings from the GUI and start video preview. 
            % Returns an empty object if 'adaptorname' is not available.
            vidObj = [];
            adaptorName = app.vidName;
            try
                imaqreset
                vidObj = videoinput(adaptorName); %get video object
                src = getselectedsource(vidObj);
                
                if contains(adaptorName, 'pcocameraadaptor') %check for PCO camera and set specific settings
                    clockSpeed = set(src,'PCPixelclock_Hz');
                    [~,idx] = max(str2num(cell2mat(clockSpeed))); %get fastest clockspeed
                    src.PCPixelclock_Hz = clockSpeed{idx}; %fast scanning mode
                    src.E2ExposureTime = 1000/str2double(app.FrameRate.Value) * 1000; %set framerate
                    binFact = app.BinningDropDown.Value; %set 4x binning by default
                    
                    try %uses different string in different adaptor versions...
                        src.B1BinningHorizontal = num2str(binFact);
                        src.B2BinningVertical = num2str(binFact);
                    catch
                        src.B1BinningHorizontal = num2str(binFact,'%02i');
                        src.B2BinningVertical = num2str(binFact,'%02i');
                    end
                elseif contains(adaptorName, "gentl")
                    % app.FrameRate.Items = set(src, "AcquisitionFrameRate");
                    % app.FrameRate.Value = src.AcquisitionFrameRate;
                    src.AutoTargetBrightness = 5.019608e-01; %TODO DOES THIS WORK
                    src.BinningHorizontal = str2double(app.BinningDropDown.Value);
                    src.BinningVertical = str2double(app.BinningDropDown.Value);
                    src.ExposureTime = app.ExposureTime.Value;
                    src.Gain = app.GaindB.Value;
                else
                    app.FrameRate.Items = set(src, 'FrameRate');
                    src.FrameRate = app.FrameRate.Value{1}; %use highest available frame rate
                end

                %setup and display live video feed in preview window
                vidRes = get(vidObj,'VideoResolution'); %WHY IS IT 1456 BY 1088 WHAT
                nbands = get(vidObj,'NumberOfBands');
                app.CurrentResolution.Text = [num2str(vidRes(1)) ' x ' num2str(vidRes(2))]; %update current resolution indicator
                imshow(zeros(vidRes(2),vidRes(1),nbands),[],'parent',app.ImagePlot);%,'InitialMagnification', 'fit'); %create image object for preview
                
                %set default camera configuration
                app.ROIposition = [0 0 vidRes];  %default ROIposition
                set(vidObj,'TriggerFrameDelay',0);
                set(vidObj,'FrameGrabInterval',1); %grab every frame
                set(vidObj,'TriggerRepeat',app.ImagesPerTrial.Value);
                set(vidObj,'ROIposition',app.ROIposition);
                set(vidObj,'FramesPerTrigger',1);
                triggerconfig(vidObj,'manual');
            catch
                vidObj = [];
            end
        end
        
        function app = CheckPath(app)
            % Look for single-letter drives, starting at a: or c: as appropriate
            try
                if ispc
                ret = {};
                for i = double('c') : double('z')
                    if exist(['' i ':' filesep], 'dir') == 7
                        ret{end+1} = [i ':']; %#ok<AGROW>
                    end
                end
            
                elseif ismac
                   ret = dir('/Volumes/');
                   ret = {ret(3:end).name};
                end
                app.DriveSelect.Value = char(ret);
            end

            cPath = java.io.File(strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:))); %make sure this is OS independent
            if (cPath.getFreeSpace / 2^30) < app.minSize && length(app.DriveSelect.Value) > 1 && cPath.getFreeSpace > 0
                answer = questdlg(['Only ' num2str((cPath.getFreeSpace / 2^30)) 'gb left on ' strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:)) filesep '. Change drive?'], ...
                    'Drive select', 'Yes', 'No', 'No, stop asking me', 'Yes');
                if strcmp(answer, 'Yes')
                    checker = true;
                    cDrive = app.DriveSelect.ValueIndex; %keep current drive index
                    while checker
                        app.DriveSelect.ValueIndex = rem(app.DriveSelect.ValueIndex,length(app.DriveSelect.Value))+1; %increase drive selection value by 1
                        cPath = java.io.File(strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:)));
                        if (cPath.getFreeSpace / 2^30) > app.minSize
                            disp(['Changed path to drive ' strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:)) filesep '. ' num2str((cPath.getFreeSpace / 2^30)) 'gb remaining.'])
                            checker = false;
                        elseif app.DriveSelect.ValueIndex == cDrive
                            disp(['Could not find a drive with more then ' num2str(app.minSize) 'gb of free space. Path unchanged.'])
                            checker = false;
                        end
                    end
                elseif strcmp(answer, 'No, stop asking me')
                    app.minSize = 0; %don't check disk size anymore
                end
            end
            
            % set basepath and look for present animals, experiment types and past recordings
            app.pathBase = [char(cPath) filesep 'WIDEFIELD' filesep strtrim(app.CameraTriggerMode.Value) filesep]; %set path of imaging code
            
            if ~exist([app.pathBase 'Animals'],'dir') %check for animal path to save data
                mkdir([app.pathBase 'Animals']) %create folder if required
            end
            try %TODO THIS MIGHT BE NONSENSE
                 app.AnimalID.Value = cellstr(app.AnimalID.Value);
            catch e
                % animalID doesn't exist yet. Shenanigans ensue.
                itemSize = size(app.AnimalID.Items);
                itemSize = itemSize(2);
                newName = app.AnimalID.Value;
                app.AnimalID.Items{itemSize + 1} = newName;
                app.AnimalID.Value = newName;
            end
            folders = dir([app.pathBase 'Animals']); %find animal folders
            folders = folders([folders.isdir] & ~strncmpi('.', {folders.name}, 1));
            checker = true;
            for iAnimals = 1:size(folders,1) %skip first two entries because they contain folders '.' and '..'
                AllAnimals{iAnimals} = folders(iAnimals).name; %get animal folders
                if checker
                    if strcmp(app.AnimalID.Value,folders(iAnimals).name) %check if current selected animal coincides with discovered folder
                        app.AnimalID.ValueIndex = iAnimals; %keep animal selection constant
                        checker = false;
                    end
                end
            end
            
            if isempty(iAnimals) %Check if any animals are found
                AllAnimals{1} = 'Dummy Subject'; %create dummy animal if nothing else is found
                mkdir([app.pathBase 'Animals' filesep 'Dummy Subject']) %create folder for default experiment
            end
            
            app.AnimalID.Items = AllAnimals; %update AnimalID selection
            if app.AnimalID.ValueIndex > length(AllAnimals)
                app.AnimalID.ValueIndex = 1; %reset indicator
            end
            
            if ~isempty(app.AnimalID.ValueIndex)
                app.pathAnimalID = app.AnimalID.Value; %update path for current animal
            end
            
            folders = dir([app.pathBase 'Animals' filesep app.AnimalID.Value]); %find Experiment folders
            folders = folders([folders.isdir] & ~strncmpi('.', {folders.name}, 1));
            for iExperiments = 1:size(folders,1) %skip first two entries because they contain folders '.' and '..'
                AllExperiments{iExperiments} = folders(iExperiments).name; %get experiment folders
            end
            if isempty(iExperiments) %Check if any experiments are found
                AllExperiments{1} = 'Default'; %create default experiment if nothing else is found
                mkdir([app.pathBase 'Animals' filesep AllAnimals{1} filesep 'Default']) %create folder for default experiment
            end

            app.ExperimentType.Items = AllExperiments; %update experiment type selection
            if size(AllExperiments,2) < app.ExperimentType.ValueIndex 
                app.ExperimentType.ValueIndex = 1; 
            end
            app.pathExpType = app.ExperimentType.Value; %update path for current experiment type
            cPath = [app.pathBase 'Animals' filesep app.AnimalID.Value filesep app.ExperimentType.Value]; %assign current path
            
            date = string(datetime(datetime, "Format", "yyyyMMdd-HHmmss"));
            if exist(strcat(cPath, filesep, date), 'dir') && length(dir(strcat(cPath, filesep, date))) > 2 %check if folder for current date exist already and contains data
                Cnt = 1;
                while exist(strcat(cPath, filesep, date, '_', num2str(Cnt)),'dir')
                    Cnt = Cnt +1; %update counter until it is ensured that current experiment name is not used already
                end
                app.pathRecordingID = strcat(date, '_', num2str(Cnt)); %set folder for recording day as the date + necessary counter
            else
                app.pathRecordingID = date; %set folder for current recording to recording date
            end
            app.RecordingID.Text = app.pathRecordingID; %update GUI
            app.DataPath.Value = strcat(cPath, filesep, app.pathRecordingID); %set complete file path for data storage
            
            AllTrials = [];
            app.TrialsPath = cPath;
            trialFolders = dir(cPath); %find Trial folders
            trialFolders = trialFolders([trialFolders.isdir] & ~strncmpi('.', {trialFolders.name}, 1));
            for iTrials = 1:size(trialFolders,1) %skip first two entries because they contain folders '.' and '..'
                AllTrials{iTrials} = trialFolders(iTrials).name; %get trial folders
            end
            if ~isempty(AllTrials)
                app.PreviousTrials.Enable = "on";
                app.ViewPreviousButton.Enable = "on";
                app.PreviousTrials.Items = AllTrials;
            else
                AllTrials{1} = 'No Previous Trials';
                app.PreviousTrials.Items = AllTrials;
                app.PreviousTrials.Enable = "off";
                app.ViewPreviousButton.Enable = "off";
            end

            app.SnapshotTaken = false; %flag for snapshot - has to be taken in order to start data acquisition
            % guidata(app.WidefieldImager, app);
        end
        
        function CheckDAQ(app)
            % https://au.mathworks.com/help/daq/daq.interfaces.dataacquisition.html
            % check NI card
            if length(app.DaqName.Items) == 1
                if ~isempty(daqlist())
                    dList = daqlist().DeviceInfo;
                    dNames = {};
                    dNames{1} = 'none';
                    for i = 1:length(dList)
                        dNames{i+1} = dList(i).ID;
                    end
                    app.DaqName.Items = dNames;
                else
                    dNames = {};
                    dNames{1} = 'none';
                    app.DaqName.Items = dNames;
                    disp("No DAQs detected.");
                    app.DaqName.Value = 'none';
                end
            end
            daqName = app.DaqName.Value;
            if daqName == 'none'
                app.dNIdevice = [];
                return
            end
            try
                daqs = daqlist().DeviceInfo;
            catch
                daqs = [];
            end

            if isempty(daqs)
                disp('No NI devices found or data acquisition toolbox missing.')
                disp('Use software triggers instead.')
                app.dNIdevice = [];
            else
                try
                    if isfield(app, 'dNIdevice')
                        delete(app.dNIdevice);
                        delete(app.aNIdevice);
                    end
                    daqs = daqlist().DeviceInfo;
                    correctIndex = 0;
                    checker = false; %check if daq device with correct ID is present. Default is 'Dev1'
                    for x = 1 : length(daqs)
                        if strcmpi(daqs(x).ID,daqName)
                            checker = true;
                            correctIndex = x;
                        end
                    end
                    if ~checker
                        warning(['Could not find specified DAQ: ' daqName ' - Using existing board ' daqs(x).ID ' instead.'])
                        daqName = daqs(x).ID;
                        app.DaqName.Value = daqs(x).ID;
                        correctIndex = x;
                    end
    
                    %Initialise DAQ with correct settings.
                    app.dNIdevice = daq(daqs(correctIndex).Vendor.ID); %object for communication with DAQ - digital lines
                    app.aNIdevice = daq(daqs(correctIndex).Vendor.ID); %object for communication with DAQ - analog lines
                    % app.aNIdevice.IsContinuous = true; %set to continous acquisition
                    app.aNIdevice.Rate = 1000; %set sampling rate to 1kHz
                    switch app.CaptureMode.Value
                        case "Active"
                            app.InitialiseActiveDAQ();
                        case "Passive"
                    end
                catch
                    disp('Error with setting up NI Device.')
                    disp('Use software triggers instead.')
                    app.dNIdevice = [];
                end
            end
        end
        
        function receiveFrame(app, src, obj)
            %TODO
            %https://au.mathworks.com/help/imaq/acquire-images-using-parallel-worker.html
            %- https://stackoverflow.com/questions/61080940/matlab-gui-warning-when-using-parfeval-unable-to-save-app-designer-app-objec

            %also todo https://au.mathworks.com/help/imaq/acquiring-image-data.html
            %AND https://au.mathworks.com/help/imaq/logging-image-data-to-disk.html
            % AND IS THERE A FAST WAY TO DO THIS??
            %- loggingmode
            % ALSO TODO WHEN YOU'RE BACK IN THE LASER ROOM - CAPTURED / TOTAL DISPLAY
            if app.AcquiringTic == 0
                % disp("set acquiring tic")
                app.AcquiringTic = tic;
            end
            % to = toc(app.LastAcquisitionTic);
            app.LastAcquisitionTic = tic;
            try
                imgs = getdata(src,src.FramesAvailable); 
                t = string(datetime(datetime, "Format", "default"));
                if app.PreviouslyCapturedTime.Text == "[none]"
                    app.RecordingID.Text = string(datetime(t, "Format", "yyyyMMdd_HHmmss"));
                    InitialiseNewTrial(app);
                else
                    prefix = string(datetime(datetime, "Format", "yyyy-MM-dd"));
                    prev = datetime(strcat(prefix, app.PreviouslyCapturedTime.Text), "Format", "yyyy-MM-ddHH:mm:ss");
                    if app.currentFrameCount >= app.ImagesPerTrial
                        app.RecordingID.Text = string(datetime(t, "Format", "yyyyMMdd_HHmmss.SSS"));
                        InitialiseNewTrial(app);
                    elseif t - prev > seconds(app.TrialTimeout.Value) %TODO RESET PREV WHEN RESET CAMERA
                        app.RecordingID.Text = string(datetime(t, "Format", "yyyyMMdd_HHmmss.SSS"));
                        InitialiseNewTrial(app);
                    end
                end
                app.PreviouslyCapturedTime.Text = string(datetime(t, "Format", "HH:mm:ss")); %todo this is more computationally complex than just storing a datetime
                
                numImgs = size(imgs);
                numImgs = numImgs(4);
                for i = 1:numImgs
                    imname = strcat(app.DataPath.Value, '\', string(app.currentFrameCount), '_', string(datetime(datetime, "Format", 'yyyyMMdd_HHmmss.SSS')), ".TIFF");
                    imwrite(imgs(:,:,:,i),imname);
                    app.currentFrameCount = app.currentFrameCount + 1;
                end

                imshow(imgs(:, :, :, 1), "Parent", app.ImagePlot);
                try
                    UpdateFluorescenceTrace(app);
                end
            catch exception
                disp("Encountered an error imaging.")
                dbstack
                disp(exception.message)
                app.UpdateLamp(app.ReadyLamp, "red");
            end
        end

        function InitialiseNewTrial(app)
            CheckPath(app);
            mkdir(string(app.DataPath.Value));
            app.TrialNr.Text = string(str2double(app.TrialNr.Text) + 1);
            if strcmp(app.CaptureMode.Value, "Active")
                dTemp = DAQInterface('Required', true, 'DaqHandle', app.dNIdevice);
                dTemp.LoadProtocol('idxStim', str2double(app.TrialNr.Text), ...
                    'p', app.ActiveParams.p, 'g', app.ActiveParams.g, ...
                    'h', app.ActiveParams.h);
            end
            app.currentFrameCount = 0;
            % CheckPath(app)
        end

        function InitialiseFluorescenceTrace(app)
            app.fluorescenceTraceLine = animatedline(app.FluorescenceTrace, 'MaximumNumPoints',100);
        end
        
        function UpdateFluorescenceTrace(app)
            if ~app.VerboseModeButton.Value
                return
            end
            if app.ROIposition == 0
                vidRes = get(app.vidObj,'VideoResolution');
                if isempty(vidRes)
                    vidRes = [app.ImagePlot.xLim(2) app.ImagePlot.yLim(2)];
                end
                app.ROIposition = [0 0 vidRes];  %default ROIposition
            elseif isempty(app.ROIposition)
                vidRes = get(app.vidObj,'VideoResolution');
                if isempty(vidRes)
                    vidRes = [app.ImagePlot.xLim(2) app.ImagePlot.yLim(2)];
                end
                app.ROIposition = [0 0 vidRes];  %default ROIposition
            end
            xMin = app.ROIposition(1)+1;
            xMax = app.ROIposition(1)+1 + app.ROIposition(3);
            yMin = app.ROIposition(2)+1;
            yMax = app.ROIposition(2)+1 + app.ROIposition(4);
            if app.ViewPreviousButton.Value == 1
                % go through image history to find fluorescence trace
                % TODO THIS IS SO INEFFICIENT
                cla(app.FluorescenceTrace);
                InitialiseFluorescenceTrace(app);
                trialFolders = app.PreviousTrials.Value;
                if isempty(trialFolders)
                    return
                end
                %load images
                fluorescences = [];
                startIndex = 1;
                endIndex = 0;
                numImages = 0;
                for iTrials = 1:length(trialFolders)
                    p = strcat(app.TrialsPath, filesep, trialFolders{iTrials});
                    imageNames = dir(p);
                    if iTrials == 1
                        numImages = length(imageNames)-2;
                        endIndex = min(app.fluorescenceTraceLine.MaximumNumPoints + app.Slider.Value, numImages);
                        startIndex = max(1, endIndex - app.fluorescenceTraceLine.MaximumNumPoints);
                    else
                        if size(imageNames)-2 ~= numImages
                            disp("Trials selected are incompatible. Number of images does not match.");
                            return
                        end
                    end
                    trialMeans = zeros(numImages, 1);
                    for iImage = startIndex:endIndex
                        img = read(Tiff(strcat(p, filesep, imageNames(iImage + 2).name)));
                        img = img(:,:,1:3);
                        img = imcrop(img, app.ROIRectangle);
                        imshow(img);
                        meanFluorescence = mean(img(:));
                        trialMeans(iImage) = meanFluorescence;
                    end
                    fluorescences{iTrials} = trialMeans;
                end
                fluorescences = cell2mat(fluorescences);
                if length(trialFolders) ~= 1
                    yValues = mean(fluorescences');
                else
                    yValues = fluorescences;
                end

                % draw it
                indicatorLine = app.Slider.Value - (startIndex - 1);
                xline(indicatorLine, 'Parent', app.FluorescenceTrace, 'LineWidth', 0.2, 'Color',[1, 0, 0]);
                xValues = linspace(startIndex, endIndex, 1+endIndex-startIndex);
                addpoints(app.fluorescenceTraceLine, xValues, yValues);
            else
                % live update
                img = getimage(app.ImagePlot);
                meanIntensity = mean(img(:));
                addpoints(app.fluorescenceTraceLine, toc(app.VerboseModeStartedTic), meanIntensity);
                % app.fluorescenceTraceLine.addpoint(app.FluorescenceTrace.XAxis.get(), meanIntensity);
            end
            axis(app.FluorescenceTrace, "tight");
            ylim(app.FluorescenceTrace, [0,255])
            drawnow
        end
        
        function UpdateLamp(app, lamp, value)
            %lord forgive us our sins, as we forgive those who sin against
            %us.
            if class(lamp) ~= "matlab.ui.control.Lamp"
                switch lamp
                    case "ReadyLamp"
                        lamp = app.ReadyLamp;
                    case "AcquiringLamp"
                        lamp = app.AcquiringLamp;
                end
            end
            switch value
                case "green"
                    lamp.Color = [0.00,1.00,0.00];
                case "grey"
                    lamp.Color = [0.94,0.94,0.94];
                case "yellow"
                    lamp.Color = [1.00,1.00,0.00];
                case "red"
                    lamp.Color = [1.00,0.00,0.00];
                case "darkgrey"
                    lamp.Color = [0.50,0.50,0.50];
                case "cyan"
                    lamp.Color = [0.00,1.00,1.00];
                case "magenta"
                    lamp.Color = [1.00,0.00,1.00];
                case "white"
                    lamp.Color = [1.00,1.00,1.00];
                otherwise 
                    disp("invalid colour: " + value)
                    lamp.Color = [0.0, 0.0, 0.0];
            end
        end
        
        function DigitalScansAvailable(app, obj, event)
            data = read(obj,obj.ScansAvailableFcnCount,"OutputFormat","Matrix");    
            if and(data == 1, app.AcquiringLamp.Color ~= [0.00, 1.00, 0.00])
                app.StartwithStimulusButtonPushed([]);
            elseif and(data == 0, app.AcquiringLamp.Color == [0.00,1.00,0.00])
                % TODO INTERRUPT ACQUISITION
            end
        end
        
        function UpdateGUIQuick(app, obj, event)
            if isempty(app.vidObj)
                app.UpdateLamp(app.ReadyLamp, "darkgrey");
                app.UpdateLamp(app.AcquiringLamp, "darkgrey");
                return
            else
                framesPercent = (app.vidObj.FramesAcquired / app.ImagesPerTrial.Value) * 100;
                app.AcquiredCounter.Text = [num2str(framesPercent) '%'];
                app.AcquiredCounter.Tooltip = [num2str(app.vidObj.FramesAcquired) '/' num2str(app.ImagesPerTrial.Value)];
            end
            if ~isrunning(app.vidObj)
                app.ReadyTic = 0;
                app.LoggingTic = 0;
                app.AcquiringTic = 0;
                if app.errorState
                    app.UpdateLamp(app.ReadyLamp, "red");
                else
                    app.UpdateLamp(app.ReadyLamp, "grey");
                end
            else
                if app.ReadyTic == 0
                    app.ReadyTic = tic;
                end
                app.UpdateLamp(app.ReadyLamp, "green");
                app.ReadyTimer.Text = datestr(datenum(0,0,0,0,0,toc(app.ReadyTic)),'HH:MM:SS');
            end
            if ~isempty(app.LastAcquisitionTic)
                if toc(app.LastAcquisitionTic) < 0.5 %if it's been acquiring in the last 0.5 seconds, say it's acquiring
                    app.UpdateLamp(app.AcquiringLamp, "green");
                    app.AcquiringTimer.Text = datestr(datenum(0,0,0,0,0,toc(app.AcquiringTic)),'HH:MM:SS');
                elseif toc(app.LastAcquisitionTic) > app.TrialTimeout.Value || app.AcquiringTic == 0
                    app.AcquiringTic = 0;
                    app.UpdateLamp(app.AcquiringLamp, "grey");
                end
            end
            if ~islogging(app.vidObj) 
                app.UpdateLamp(app.LoggingLamp, "grey");
                app.LoggingTic = 0;
            else 
                if app.LoggingTic == 0
                    app.LoggingTic = tic;
                end
                app.UpdateLamp(app.LoggingLamp, "green");
                app.LoggingTimer.Text = datestr(datenum(0,0,0,0,0,toc(app.LoggingTic)),'HH:MM:SS');
            end
            if app.VerboseModeButton.Value == 1
                try
                    UpdateFluorescenceTrace(app);
                end
            end
        end
        
        function SetNoisyWarnings(app, value)
            warning('imaq:gentl:adaptorDimChangeResettingROI',value);
        end
        
        function ToggleInteractivity(app, state, varargin)
            p = inputParser;
            addParameter(p, "exceptions", []);
            addParameter(p, "targets", []);
            parse(p, varargin{:});
            exceptions = p.Results.exceptions;
            targets = p.Results.targets;
            if ~isempty(exceptions) % exclude specific
                allUI = findobj(app.MainPanel);
                for ii = 1:length(allUI)
                    uiObj = allUI(ii);
                    if contains(class(uiObj), 'matlab.ui.control') ...
                            && isprop(uiObj, "Enable") ...
                            && ~any(uiObj == exceptions) && uiObj ~= app.DebugButton ...
                            && ~contains(class(uiObj), 'Lamp') ...
                            && ~contains(class(uiObj), 'Label')
                        uiObj.Enable = state;
                    end
                end
            elseif ~isempty(targets) % target specific
                for i = 1:length(targets)
                    if contains(class(targets(i)), 'matlab.ui.control') % single UI element
                        uiObj.Enable = state;
                    else % container
                        allUI = findObj(targets(i));
                        for ii = 1:length(allUI)
                            uiObj = allUI(ii);
                            if contains(class(uiObj), 'matlab.ui.control') ...
                                    && isprop(uiObj, "Enable") && uiObj ~= app.DebugButton
                                uiObj.Enable = state;
                            end
                        end
                    end
                end
            else %just do them all 
                allUI = findobj(app.MainPanel);
                for ii = 1:length(allUI)
                    uiObj = allUI(ii);
                    if contains(class(uiObj), 'matlab.ui.control') ...
                            && isprop(uiObj, "Enable") && uiObj ~= app.DebugButton
                        uiObj.Enable = state;
                    end
                end
            end
        end
        
        function ToggleLoadingState(app, state, source, message)
            switch state
                case "on"
                    app.ToggleInteractivity("off", "exceptions", ...
                        [source]);
                    app.UpdateLamp(app.LoadingIndicatorLamp, "yellow");
                    app.LoadingCompletedLampLabel.Text = message;
                case "off"
                    app.ToggleInteractivity("on", "exceptions", ...
                        [source]);
                    app.UpdateLamp(app.LoadingIndicatorLamp, "grey");
                    app.LoadingCompletedLampLabel.Text = "Loading Complete";
            end
        end
        
        function UpdateCameraROI(app, ROI)
            try
                delete(app.ROIRectangle);
            end
            try
                closepreview(app.vidObj);
                stop(app.vidObj);
            end
            app.CurrentResolution.Text = [num2str(ROI(3)) ' x ' num2str(ROI(4))]; %update current resolution indicator
            app.ROIposition = ROI;
            app.CameraROI.Value = mat2str(ROI);
            set(app.vidObj,'ROIposition',app.ROIposition);

            vidRes = get(app.vidObj,'VideoResolution');
            if all(ROI == [0 0 vidRes])
                app.ResetCameraROI.enable = "off";
            else
                app.ResetCameraROI.enable = "on";
            end

            % show image
            snap = getsnapshot(app.vidObj); hold(app.ImagePlot,'off');
            imshow(snap,[],'parent',app.ImagePlot);
            maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
            cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
            colormap(app.ImagePlot,cMap);
            preview(app.vidObj,app.ImagePlot.Children); %resume preview
            UpdateFluorescenceTrace(app);
        end
        
        
        function app = InitialiseActiveDAQ(app)
            if isempty(fields(app.ActiveParams))
                % use default parameters
                % app.dNIdevice.addinput(daqName, 'port0/line0', "Digital");
                app.dNIdevice.addoutput(daqName, "port0/line4", "Digital");
                app.dNIdevice.addoutput(daqName, "port1/line4", "Digital");
                app.dNIdevice.ScansAvailableFcn = @app.DigitalScansAvailable;
                app.dNIdevice.ScansAvailableFcnCount = 100; %TODO MIGHT BE TOO SMALL (fast)
                addclock(app.dNIdevice,"ScanClock","External","Dev1/PFI0");
            else
                d = DAQInterface();
            end
            try
                app.dNIdevice.start();
            end
            % app.dNIdevice.write(0); %make sure outputs are set to 0 to start.
            % app.aNIdevice.ScansAvailableFcn = @app.AnalogScansAvailable;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.daqName = "Dev1";
            warning('off', 'MATLAB:JavaEDTAutoDelegation'); %TODO REMOVE THIS but currently the imaq toolbox is noisyyy
            app.ToggleLoadingState("on", [], "Starting Application...");
            app.extraFrames = 5;
            app.minSize = 100;
            app.serverPath = '\\your server path';

            %turn off slider until history is viewed
            if app.Slider.Enable == "on"
                app.Slider.Enable = "off";
                app.Slider.Visible = "off";
            end
            
            InitialiseFluorescenceTrace(app);

            % Initialise NI card
            CheckDAQ(app);

            %% initialize camera
            info = imaqhwinfo; %todo this throws a lott of wwarnings??
            app.vidName = [];
            camCheck = contains(info.InstalledAdaptors, 'pcocameraadaptor');
            
            if any(camCheck)
                app.vidName = info.InstalledAdaptors{camCheck}; %PCO camera
            end
            app.vidObj = CheckCamera(app); %get PCO video object
            
            % check for other cameras if PCO adaptor is unavailable.
            if isempty(app.vidObj)
                disp('PCO camera not available. Searching for other cameras.');
                app.vidName = [];
                if length(info.InstalledAdaptors) == 1
                    app.vidName = info.InstalledAdaptors{1};
                elseif any(contains(info.InstalledAdaptors, 'gentl'))
                    app.vidName = 'gentl';
                elseif length(info.InstalledAdaptors) > 1 %multiple adaptors present, ask for selection
                    out = listdlg('Name', 'Please select your camera', ...
                        'SelectionMode','single','liststring',info.InstalledAdaptors,'listsize',[300 300]);
                    if ~isempty(out)
                        app.vidName = info.InstalledAdaptors{out};
                    end
                end
                %binning only works with PCO & basler camera so disable for other cameras TODO MAKE IT WORK FOR OTHER CAMERAS
            end
            
            if ~isempty(app.vidName)
                fprintf('Using %s as current video adapter\n',app.vidName);
                app.vidObj = CheckCamera(app); %get non-PCO video object
                
                if ~isempty(app.vidObj)
                    preview(app.vidObj, app.ImagePlot.Children); %start preview %TODO THIS THROWS A WARNING
                    axis(app.ImagePlot, "tight");
                    maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                    cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                    colormap(app.ImagePlot,cMap);
                    app.BinningDropDownValueChanged(1);
                    CaptureModeValueChanged(app, 0);
                    CameraTriggerModeValueChanged(app, []);
                end
            end
            
            if isempty(app.vidObj)
                warning('No camera found. Type "imaqhwinfo" to make sure your camera is properly installed and running.')
            end

            if any(ismember(app.DriveSelect.Value(:,1), 'g')) %start on G: drive by default
                app.DriveSelect.Value = find(ismember(app.DriveSelect.Value(:,1), 'g'));
            end
            
            % set timer for calibration mode
            CheckPath(app); %Check for data path, reset date and trialcount

            ticks = [app.LastAcquisitionTic, app.AcquiringTic, app.LoggingTic, app.ReadyTic];
            for i = 1:length(ticks)
                ticks(i) = 0;
            end
            
            % start parallel image acquisition TODO CHECK THIS
            % parpool('local', 1);
            % app.ImageWriteThread = parfeval(@app.receiveFrame,0);
            
            % start GUI update timer process
            try
                stop(timerfind("Name", "WidefieldGuiTimer"));
                delete(timerfind("Name", "WidefieldGuiTimer")); %remove previous timers
            end
            app.GuiTimer = timer("Period", 0.2, "ExecutionMode", "FixedSpacing", "Name", "WidefieldGuiTimer");
            app.GuiTimer.TimerFcn = @app.UpdateGUIQuick;
            start(app.GuiTimer);
            app.ToggleLoadingState("off", [], "");
        end

        % Value changed function: CameraTriggerMode
        function CameraTriggerModeValueChanged(app, event)
            value = app.CameraTriggerMode.Value;
            src = getselectedsource(app.vidObj);
            img = getimage(app.ImagePlot);
            stoppreview(app.vidObj);
            if isrunning(app.vidObj)
                stop(app.vidObj);
                app.UpdateLamp(app.ReadyLamp, "grey");
            end
            switch value
                case "Hardware Triggered"
                    %--configure hardware--
                    app.vidObj.FramesPerTrigger = 1; %TODO make this configurable. buffering issues atm.
                    src.LineSelector = "Line4";
                    src.LineMode = "Input";
                    src.TriggerSelector = "FrameStart";
                    src.TriggerMode = "On";
                    src.TriggerSource = "Line4";
                    src.TriggerActivation = "RisingEdge";
                    triggerconfig(app.vidObj, "hardware");
                    %--update GUI--
                    if app.PreviewButton.Value == 1
                        app.PreviewButton.Value = 0;
                    end
                    if length(app.ImagePlot.Children) > 1
                        % delete text object
                        delete(app.ImagePlot.Children(1));
                    end
                    % add warning text
                    imshow(img, 'Parent', app.ImagePlot);
                    s = size(img);
                    x = mean([0, s(2)]);
                    y = mean([0, s(1)]);
                    text(x,y, 'LIVE PREVIEW NOT AVAILABLE IN HARDWARE TRIGGER MODE', ...
                        'Parent', app.ImagePlot, 'FontSize', 16, 'FontWeight','bold', ...
                        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                        'Color', 'red');
                case "Software Triggered"
                    triggerconfig(app.vidObj, "manual");
                    src.TriggerSource = "Software";
                    app.vidObj.FramesPerTrigger = 1;
                case "Free Run"
                    triggerconfig(app.vidObj, "immediate");
            end
            if ~strcmpi(value, "Hardware Triggered")
                if length(app.ImagePlot.Children) > 1
                    % delete text object
                    delete(app.ImagePlot.Children(1));
                end
                preview(app.vidObj, app.ImagePlot.Children);
                app.PreviewButton.Value = 1;
            end
            axis(app.ImagePlot, "tight");
            app.UpdateLamp(app.ReadyLamp, "yellow");
        end

        % Value changed function: ExposureTime
        function ExposureTimeValueChanged(app, event)
            value = app.ExposureTime.Value;
            src = getselectedsource(app.vidObj);
            src.ExposureTime = value;
            % CameraPropertiesChanged(app);
        end

        % Value changed function: GaindB
        function GaindBValueChanged(app, event)
            value = app.GaindB.Value;
            src = getselectedsource(app.vidObj);
            src.Gain = value;
            % CameraPropertiesChanged(app);
        end

        % Button pushed function: TakeSnapshotButton
        function TakeSnapshotButtonPushed(app, event)
            if app.vidObj == 0
                disp('Snapshot not available. Check if camera is connected and restart.')
            else
                h = figure('Toolbar','none','Menubar','none','NumberTitle','off','Name','Snapshot'); %create figure to show snapshot
                snap = getsnapshot(app.vidObj); %get snapshot from video object
                temp = ls(app.pathBase); %check if earlier snapshots exist
                temp(1,8)=' '; % make sure temp has enough characters
                temp = temp(sum(ismember(temp(:,1:8),'Snapshot'),2)==8,:); %only keep snapshot filenames
                temp(~ismember(temp,'0123456789')) = ' '; %replace non-integer characters with blanks
                cNr = max(str2num(temp)); %get highest snapshot nr
                cNr(isempty(cNr)) = 0; %replace empty with 0 if no previous snapshot existed
                save([app.pathBase 'Snapshot_' num2str(cNr+1) '.mat'],'snap') % snapshot
                imwrite(mat2gray(snap),[app.pathBase 'Snapshot_' num2str(cNr+1) '.jpg']) %save snapshot as jpg
            
                %     imshow(snap,'XData',[0 1],'YData',[0 1]); colormap gray; axis image;
                imshow(snap); axis image; title(['Saved as Snapshot ' num2str(cNr+1)]);
                uicontrol('String','Close','Callback','close(gcf)','units','normalized','position',[0 0 0.15 0.07]); %close button
                app.SnapshotTaken = true; %update snapshot flag
            end
        end

        % Button pushed function: DrawROIButton, SelectCameraROI
        function SelectROIButtonPushed(app, event)
            if and(isempty(app.vidObj), app.ViewPreviousButton.Value == 0)
                disp('ROI change not available. Check if camera is connected and restart.')
                return
            elseif or(app.ViewPreviousButton.Value == 1, event.Source == app.DrawROIButton)
                % select without cropping
                try
                    delete(app.ROIRectangle);
                end
                [~, ROI] = imcrop(app.ImagePlot);
                ROI = floor(ROI);
                if ~isempty(ROI)
                    app.ROIposition = ROI;
                    app.ROIRectangle = rectangle('Position', ROI, 'Parent', app.ImagePlot);
                    drawnow
                end
            else 
                % crop select
                closepreview(app.vidObj) %stop camera, and set new ROI position
                stop(app.vidObj) %stop camera
                snap = getsnapshot(app.vidObj);
                imshow(snap,'Parent',app.ImagePlot);
                [~,ROI] = imcrop(app.ImagePlot);
                ROI = floor(ROI);
                if ~isempty(ROI)
                    app.UpdateCameraROI(ROI);
                end
            end
            %     if ~isempty(ROI)
            %         app.CurrentResolution.Value = [num2str(ROI(3)) ' x ' num2str(ROI(4))]; %update current resolution indicator
            %         h = figure; %create temporary figure for saving the roi selection
            % %         imshow(snap,'XData',[0 1],'YData',[0 1]); hold on %plot current view
            %         imshow(snap); hold on %plot current view
            %         xVals = [repmat(ROI(1),1,2) repmat(ROI(1)+ROI(3),1,2) ROI(1)];
            %         yVals = [ROI(2) repmat(ROI(2)+ROI(4),1,2) repmat(ROI(2),1,2)];
            %         plot(xVals,yVals,'r','linewidth',2) %plot ROI outline
            %         savefig(gcf,[app.pathBase 'ROI.fig']) %save ROI figure
            %         saveas(h,[app.pathBase 'ROI.jpg']) %save ROI as jpg
            %         close
            % 
            %         %update preview TODO make it so you don't necessarily
            %         %crop the whole image
            %         app.ROIposition = ROI;
            %         set(app.vidObj,'ROIposition',app.ROIposition);
            %         snap = getsnapshot(app.vidObj); hold(app.ImagePlot,'off');
            %         %         imshow(snap,[],'parent',handles.ImagePlot,'XData',[0 1],'YData',[0 1]);
            %         imshow(snap,[],'parent',app.ImagePlot);
            %     end
            % 
            %     maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
            %     cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
            %     colormap(app.ImagePlot,cMap);
            %     preview(app.vidObj,app.ImagePlot.Children); %resume preview
            % end
            % UpdateFluorescenceTrace(app);
        end

        % Button pushed function: ResetCameraROI, ResetROIButton
        function ResetROIButtonPushed(app, event)
            if and(isempty(app.vidObj), app.ViewPreviousButton.Value == 0)
                disp('ROI change not available. Check if camera is connected and restart.')
                return
            elseif or(app.ViewPreviousButton.Value == 1, event.Source == app.ResetROIButton)
                %% select ROI previous trials & DO NOT CROP
                vidRes = get(app.vidObj,'VideoResolution');
                app.ROIposition = [0 0 vidRes];  %default ROIposition
                try
                    delete(app.ROIRectangle)
                end
                drawnow
            else 
                vidRes = get(app.vidObj,'VideoResolution');
                app.ROIposition = [0 0 vidRes];  %default ROIposition
                app.UpdateCameraROI(app.ROIposition);
            %     stoppreview(app.vidObj) %stop camera
            %     stop(app.vidObj) %stop camera
            %     set(app.vidObj,'ROIposition',app.ROIposition); % set new ROI position
            %     snap = getsnapshot(app.vidObj); %get snapshot from video object
            % %     imshow(snap,[],'parent',handles.ImagePlot,'XData',[0 1],'YData',[0 1]); hold(handles.ImagePlot,'off'); %plot current view
            %     imshow(snap,[],'parent',app.ImagePlot); hold(app.ImagePlot,'off'); %plot current view
            %     colormap(app.ImagePlot,'gray');
            %     preview(app.vidObj,app.ImagePlot.Children); %resume preview
            %     maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
            %     cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
            %     colormap(app.ImagePlot,cMap);
            % 
                % update current resolution in GUI
                % imHeight = app.ROIposition(3)-app.ROIposition(1);
                % imWidth = app.ROIposition(4)-app.ROIposition(2);
                % app.CurrentResolution.Value = [num2str(imWidth) ' x ' num2str(imHeight)]; %update current resolution indicator
            end
        end

        % Value changed function: CaptureMode
        function CaptureModeValueChanged(app, event)
            value = app.CaptureMode.Value;
            CheckDAQ(app);
            switch value
                case "Passive"
                    if isrunning(app.vidObj)
                        stop(app.vidObj);
                    end
                    app.SendTrigger.Enable = 'off';
                    app.vidObj.FramesAcquiredFcnCount = 10;
                    app.vidObj.FramesAcquiredFcn = @app.receiveFrame;
                case "Active"
                    app.SendTrigger.Enable = 'on';
                    app.InitialiseActiveAcquisition();
            end
            CheckDAQ(app);
        end

        % Value changed function: TogglePassiveAcquisition
        function TogglePassiveAcquisitionValueChanged(app, event)
            % TODO https://au.mathworks.com/help/imaq/videoinput.html
            % https://au.mathworks.com/help/parallel-computing/quick-start-parallel-computing-in-matlab.html
            value = app.TogglePassiveAcquisition.Value;
            if isempty(app.vidObj)
                app.TogglePassiveAcquisition.Value = 0;
                return
            end
            if value == 1
                % pressed start
                if ~isrunning(app.vidObj)
                    start(app.vidObj);
                end
                app.TogglePassiveAcquisition.Text = "Stop Passive Acquisition";
                if isrunning(app.vidObj)
                    app.UpdateLamp(app.ReadyLamp, "green");
                end
                app.ToggleLoadingState("on", ...
                    [app.TogglePassiveAcquisition, app.NewTrialButton], "Acquiring...");
            else
                %pressed stop
                stop(app.vidObj);
                app.TogglePassiveAcquisition.Text = "Start Passive Acquisition";
                if ~isrunning(app.vidObj)
                    app.UpdateLamp(app.ReadyLamp, "grey");
                end
                app.ReadyTic = 0;
                app.ToggleLoadingState("off", ...
                    [app.TogglePassiveAcquisition, app.NewTrialButton], "Acquisition Complete");
            end
        end

        % Button pushed function: SendTrigger
        function SendTriggerButtonPushed(app, event)
            % https://au.mathworks.com/help/daq/daq.interfaces.dataacquisition.html
            % https://au.mathworks.com/help/daq/daq.interfaces.dataacquisition.write.html
            %check vidobj.FramesAcquiredFcn, src input modes
            src = getselectedsource(app.vidObj);
            if ~isrunning(app.vidObj)
                start(app.vidObj);
                disp("Video object not started. Starting video object");
                if isrunning(app.vidObj)
                    app.UpdateLamp(app.ReadyLamp, "green");
                else
                    app.UpdateLamp(app.ReadyLamp, "red");
                    return
                end
            end
            if app.DaqName.Value == 'none'
                return
            end
            switch app.CameraTriggerMode.Value
                case "Hardware Triggered"
                    write(app.dNIdevice, 1);
                    % pause(1)
                    % pause(app.ExposureTime / 1000000);
                    write(app.dNIdevice, 0);
                    app.TriggerLastSent.Text = string(datetime(datetime, "Format", "HHmmss"));
                case "Software Triggered"
                    trigger(app.vidObj)
                    app.TriggerLastSent.Text = string(datetime(datetime, "Format", "HHmmss"));
                case "Free Run"
                    %what are you doing here.
                    disp("TODO grey out this button when the trigger mode is auto");
            end
        end

        % Button pushed function: NewAnimalButton
        function NewAnimalButtonPushed(app, event)
            if event.Source == app.AnimalID
                newMouse = {app.AnimalID.Value};
            else
                app = CheckPath(app); %Check for data path, reset date and trialcount
                dPrompt = {'Enter animal ID'};
                pName = 'New animal';
                newMouse = inputdlg(dPrompt,pName,1,{'New mouse'});
            end
            if ~isempty(newMouse)
                mkdir([app.pathBase 'Animals' filesep newMouse{1}])
                newExp = {app.ExperimentType.Value};
                if ~isempty(newExp)
                    mkdir([app.pathBase 'Animals' filesep app.AnimalID.Value filesep newExp{1}])
                    app = CheckPath(app); %Check for data path, reset date and trialcount
                    app.ExperimentType.Value = newExp{1};
                    app.TrialNr.Text = '1';
                    CheckPath(app);
                end
            end
        end

        % Button pushed function: NewExperimentButton
        function NewExperimentButtonPushed(app, event)
            dPrompt = {'Enter experiment ID'};
            pName = 'New experiment';
            newExp = inputdlg(dPrompt,pName,1,{'Default'});
            if ~isempty(newExp)
                filepath = [app.pathBase 'Animals' filesep app.AnimalID.Value filesep newExp]; %TODO YOU DID SOME NONSENSE HERE !!!!
                mkdir([filepath{:}]);
                app = CheckPath(app); %Check for data path, reset date and trialcount
                app.ExperimentType.Value = newExp;
                CheckPath(app);
            end
        end

        % Button pushed function: NewTrialButton
        function NewTrialButtonPushed(app, event)
            InitialiseNewTrial(app);
        end

        % Value changed function: VerboseModeButton
        function VerboseModeButtonValueChanged(app, event)
            %this changes a value to be used in other parts of the code. 
            % no callback required here.
            verboseMode = app.VerboseModeButton.Value;
            if verboseMode
                app.VerboseModeStartedTic = tic;
            else
                app.VerboseModeStartedTic = 0;
                clearpoints(app.fluorescenceTraceLine);
            end
            % bloat = [app.StimulusDisplay, app.FluorescenceTrace];
            % set(bloat, "Enable",  value);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            %round to nearest whole number
            [~, minIdx] = min(abs(value - app.Slider.MinorTicks(:)));
            event.Source.Value = app.Slider.MinorTicks(minIdx);
            value = app.Slider.MinorTicks(minIdx);

            trialFolders = app.PreviousTrials.Value;
            if or(isempty(trialFolders), app.ViewPreviousButton.Value == 0)
                app.Slider.Value =1;
                app.Slider.Enable = "off";
                return
            end
            averageImage = 0;
            imCount = 0;
            if length(trialFolders) == 1
                p = strcat(app.TrialsPath, filesep, trialFolders{1});
                imageNames = dir(p);
                if length(imageNames) <= 2
                    app.Slider.Value = 1;
                    return
                end
                imName = strcat(p, filesep, imageNames(value+2).name);
                image = read(Tiff(imName));
                imshow(image, "Parent", app.ImagePlot); 
                % imshow(image(:,:,1:3), "Parent", app.ImagePlot); %TODO THIS DOES IGNORE 4+th CHANNELS
            else
                for iTrials = 1:length(trialFolders)
                    p = strcat(app.TrialsPath, filesep, trialFolders{iTrials});
                    imageNames = dir(p);
                    try
                        imName = strcat(p, filesep, imageNames(value+2).name);
                        currentImage = read(Tiff(imName));
                        currentImage = im2double(currentImage);
                        % currentImage = im2double(currentImage(:, :, 1:3));
                        if iTrials == 1
                            averageImage = currentImage;
                            imCount = imCount + 1;
                        else
                            averageImage = averageImage + currentImage;
                            imCount = imCount + 1;
                        end
                    catch
                        disp(strcat("Unable to load image", string(value), "from", p, ". Index may be out of bounds."));
                    end
                end
                if imCount == 0
                    app.Slider.Value = 1;
                    return
                end
                averageImage = averageImage / imCount;
                imshow(averageImage, [], "Parent", app.ImagePlot);
                % imshow(averageImage(:,:,1:3), [], "Parent", app.ImagePlot);
                try %todo debug and get rid of try catch
                    app.UpdateFluorescenceTrace();
                end
            end
        end

        % Value changed function: ViewPreviousButton
        function ViewPreviousButtonValueChanged(app, event)
            app.ToggleLoadingState("on", app.ViewPreviousButton, "Switching View Mode")
            value = app.ViewPreviousButton.Value;
            app.UpdateLamp(app.ReadyLamp, "grey");
            if isrunning(app.vidObj)
                stop(app.vidObj)
            end
            try
                clearpoints(app.fluorescenceTraceLine);
            end
            if value == 0
                app.ViewPreviousButton.Text = "View Previous Trials";
                if app.Slider.Enable == "on"
                    app.Slider.Enable = "off";
                    app.Slider.Visible = "off";
                end
                % restart acquisition as normal
                startupFcn(app); %todo is this overkill?
            else
                trialFolders = app.PreviousTrials.Value;
                if isempty(trialFolders)
                    app.ViewPreviousButton.Value = 0;
                    return
                end
                app.ViewPreviousButton.Text = "Resume Live Preview";
                maxImageIndex = 0;
                for iTrials = 1:length(trialFolders)
                    TrialImages = [];
                    p = strcat(app.TrialsPath, filesep, trialFolders{iTrials});
                    imageNames = dir(p); 
                    maxImageIndex = max(maxImageIndex, length(imageNames) - 2);
                end
                if app.Slider.Enable == "off"
                    app.Slider.Enable = "on";
                    app.Slider.Visible = "on";
                end
                app.Slider.Limits = [1, maxImageIndex];
                app.Slider.MinorTicks = linspace(1, maxImageIndex, maxImageIndex);
                try
                    %try to continue at same image index.
                    SliderValueChanged(app, []);
                catch
                    app.Slider.Value = 1;
                    SliderValueChanged(app, []);
                end
                app.ToggleLoadingState("off", app.ViewPreviousButton, "");
            end
        end

        % Value changed function: AnimalID, ExperimentType
        function AnimalIDValueChanged(app, event)
            if event.Edited
                if event.Source == app.AnimalID
                    app.NewAnimalButtonPushed(event)
                else
                    newExp = app.ExperimentType.Value;
                    mkdir([app.pathBase 'Animals' filesep app.AnimalID.Value filesep newExp])
                    app = CheckPath(app); %Check for data path, reset date and trialcount
                    app.ExperimentType.Value = newExp;
                end
            end
            CheckPath(app);
        end

        % Key press function: MainPanel
        function MainPanelKeyPress(app, event)
            key = event.Key;
            %TODO
        end

        % Callback function
        function AwaitStartButtonPushed(app, event)
            %https://au.mathworks.com/help/daq/ref/daq.session.addlistener.html
            app.AwaitStartButton.Enable = 'off';
            if app.dNIdevice.Running
                UpdateLamp(app, app.AcquiringLamp, "yellow");
            else
                disp("DAQ device not running. Use the Instant Start button to trigger acquisition or restart program");
                UpdateLamp(app, app.AcquiringLamp, "red");
            end
        end

        % Button pushed function: StartwithStimulusButton
        function StartwithStimulusButtonPushed(app, event)
          %   InitialiseNewTrial(app);
          %   UpdateLamp(app, app.AcquiringLamp, "green");
          %   app.Timer = timer('TimerFcn','@app.ExecuteTimer',...
          % 'ExecutionMode','fixedRate','Period',app.TargetFPSSpinner.Value / 60);
          %   start(app.Timer);
          %   UpdateLamp(app, app.AcquiringLamp, "yellow");
        end

        % Callback function
        function TargetFPSSpinnerValueChanged(app, event)
            value = app.TargetFPSSpinner.Value;
            app.dNIdevice.Rate = value;
        end

        % Value changed function: DaqName
        function DaqNameValueChanged(app, event)
            value = app.DaqName.Value;
            affected = [app.SendTrigger];
            app.ConfigFilePath.Value = ''; %choosing new daq resets config
            if strcmp(value, 'none')
                % if ~isrunning(app.vidObj)
                %     start(app.vidObj);
                % end
                for a = 1:length(affected)
                    affected(a).Enable = "off";
                end
            else
                for a = 1:length(affected)
                    affected(a).Enable = "on";
                end
            end
            CheckDAQ(app);
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            disp("[DEBUG] button");
            keyboard;
            UpdateFluorescenceTrace(app);
            src = getselectedsource(app.vidObj);
            vid = app.vidObj;
            disp("DEBUG BUTTON");
        end

        % Button pushed function: ResetCameraButton
        function ResetCameraButtonPushed(app, event)
            if isrunning(app.vidObj)
                stop(app.vidObj);
            end
            start(app.vidObj);
            
        end

        % Callback function
        function CameraPropertiesChanged(app, event)
            app.vidObj = CheckCamera(app);
            vidRes = get(app.vidObj,'VideoResolution'); %WHY IS IT 1456 BY 1088 WHAT
            nbands = get(app.vidObj,'NumberOfBands');
            app.CurrentResolution.Text = [num2str(vidRes(1)) ' x ' num2str(vidRes(2))]; %update current resolution indicator
            imshow(zeros(vidRes(2),vidRes(1),nbands),[],'parent',app.ImagePlot);%,'InitialMagnification', 'fit'); %create image object for preview
            app.ROIposition = [0 0 vidRes];  %default ROIposition

            preview(app.vidObj, app.ImagePlot.Children); %start preview %TODO preview
            axis(app.ImagePlot, "tight");
            maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
            cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
            colormap(app.ImagePlot,cMap);
            CaptureModeValueChanged(app, 0);
            CameraTriggerModeValueChanged(app, []);
        end

        % Value changed function: ImagesPerTrial, TrialTimeout
        function ImagesPerTrialValueChanged(app, event)
            app.UpdateLamp(app.ReadyLamp, "grey");
            if isrunning(app.vidObj)
                stop(app.vidObj);
            end
            set(app.vidObj,'TriggerRepeat',app.ImagesPerTrial.Value);
            % set(app.vidObj, 'TriggerRepeat') TODO
        end

        % Close request function: MainPanel
        function MainPanelCloseRequest(app, event)
            if ~isempty(app.GuiTimer) && strcmpi(app.GuiTimer.Running, 'on')
                stop(app.GuiTimer);
                delete(app.GuiTimer);
            end
            % clear app.ImageWriteThread;
            if ~isempty(app.vidObj)
                stop(app.vidObj);
            end
            imaqreset;
            delete(gcp("nocreate"));
            delete(app);
        end

        % Value changed function: PreviewButton
        function PreviewButtonValueChanged(app, event)
            value = app.PreviewButton.Value;
            if strcmpi(triggerconfig(app.vidObj).TriggerType, 'hardware')
                app.PreviewButton.Value = 0;
                return
            end
            if value == 1
                preview(app.vidObj, app.ImagePlot.Children); %start preview
            else
                stoppreview(app.vidObj);
            end
        end

        % Value changed function: BinningDropDown
        function BinningDropDownValueChanged(app, event)
            app.ToggleLoadingState("on", app.BinningDropDown, "Updating Binning...");
            warning ('off','all');
            if ~isempty(app.GuiTimer) && app.GuiTimer.Running == "on"
                % pause GUI timer to prevent clash errors
                stop(app.GuiTimer);
            end
            imaqreset;
            app.vidObj = CheckCamera(app);
            src = getselectedsource(app.vidObj);
            binFact = app.BinningDropDown.Value;
            if contains(app.vidName, 'pcocameraadaptor') %check for PCO camera and set specific settings
                try %uses different string in different adaptor versions...
                    src.B1BinningHorizontal = num2str(binFact);
                    src.B2BinningVertical = num2str(binFact);
                catch
                    src.B1BinningHorizontal = num2str(binFact,'%02i');
                    src.B2BinningVertical = num2str(binFact,'%02i');
                end
            elseif contains(app.vidName, "gentl")
                src.BinningHorizontal = str2double(app.BinningDropDown.Value);
                src.BinningVertical = str2double(app.BinningDropDown.Value);
            end

            vidRes = get(app.vidObj,'VideoResolution'); %todo this update lags behind?
            nbands = get(app.vidObj,'NumberOfBands');
            app.CurrentResolution.Text = [num2str(vidRes(1)) ' x ' num2str(vidRes(2))]; %update current resolution indicator
            imshow(zeros(vidRes(2),vidRes(1),nbands),[],'parent',app.ImagePlot);%,'InitialMagnification', 'fit'); %create image object for preview

            preview(app.vidObj, app.ImagePlot.Children); %start preview %TODO preview
            axis(app.ImagePlot, "tight");

            vidRes = get(app.vidObj,'VideoResolution'); %todo this update lags behind?
            nbands = get(app.vidObj,'NumberOfBands');
            app.ROIposition = [0 0 vidRes];  %default ROIposition
            app.CurrentResolution.Text = [num2str(vidRes(1)) ' x ' num2str(vidRes(2))]; %update current resolution indicator
            warning('on', 'all');
            CameraTriggerModeValueChanged(app, []);
            if ~isempty(app.GuiTimer)
                start(app.GuiTimer);
            end
            if contains(class(event), 'matlab.ui.eventdata')
                % not called from startupfcn
                app.ToggleLoadingState("off", app.BinningDropDown, "");
            end
        end

        % Value changed function: CameraROI
        function CameraROIValueChanged(app, event)
            value = app.CameraROI.Value;
            if strcmpi(value, "[default]") || strcmpi(value, "default")
                vidRes = get(app.vidObj,'VideoResolution');
                ROI = [0 0 vidRes];
            else
                tempROI = split(value, ' ');
                ROI = [str2double(tempROI{1}) str2double(tempROI{2}) ...
                    str2double(tempROI{3}) str2double(tempROI{4})];
            end
            app.UpdateCameraROI(ROI);
        end

        % Button pushed function: LoadStimButton
        function LoadStimButtonPushed(app, event)
            % TODO figure out the actual workflow for this
            if isempty(app.StimFilePath.Value)
                [filename, filepath] = uigetfile('WidefieldImager/protocolfiles/*.qst');
                if filename ~= 0
                    app.StimFilePath.Value = [filepath filename];
                else
                    return
                end
            end
            if ~isfolder("WidefieldImager/common") %|| isfolder("QSTControl/components")
                warning("This app uses a version of readParameters available in WidefieldImager/common. " + ...
                    "Including QSTcontrol/components will make MATLAB read from the wrong version of the " + ...
                    "parameters. Please check that WidefieldImager/common is on your MATLAB path and that " + ...
                    "QSTcontrol/components is not.");
            end
            [p, g, h] = readParameters(app.StimFilePath.Value);
            app.ActiveParams.p = p;
            app.ActiveParams.g = g;
            app.ActiveParams.h = h;

            dTemp = DAQInterface('Required', true, 'DaqHandle', app.dNIdevice);
            dTemp.LoadProtocol('idxStim', app.TrialNr.Text, 'p', p, 'g', g, 'h', h);
            
            app.dNIdevice = dTemp.SessionHandle;

        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            if isempty(app.ConfigFilePath.Value)
                [filename, filepath] = uigetfile('WidefieldImager/paramfiles/*.csv');
                if filename ~= 0
                    app.ConfigFilePath.Value = [filepath filename];
                else
                    return
                end
            end
            
            app.ToggleLoadingState("on", [], "Loading DAQ Config");
            dTemp = DAQInterface('Required', true, ...
                'ConfigFolder', app.ConfigFilePath.Value, ...
                'DaqHandle', app.dNIdevice);
            
            app.dNIdevice = dTemp.SessionHandle;
            if ~strcmp(app.dNIdevice.Channels(1).Device.ID, app.DaqName.Value)
                % god knows why the daq interface doesn't have the device
                % ID just accessible but this is the best I can do.
               app.DaqName.Value = app.dNIdevice.Channels(1).Device.ID;
            end
            app.ToggleLoadingState("off", [], "Loading Complete");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainPanel = uifigure('Visible', 'off');
            app.MainPanel.Name = 'MATLAB App';
            app.MainPanel.CloseRequestFcn = createCallbackFcn(app, @MainPanelCloseRequest, true);
            app.MainPanel.KeyPressFcn = createCallbackFcn(app, @MainPanelKeyPress, true);
            app.ExperimentInformationPanel = uipanel(app.MainPanel);
            app.ExperimentInformationPanel.Title = 'Experiment Information';
            app.ExperimentInfoGrid = uigridlayout(app.ExperimentInformationPanel);
            app.NewAnimalButton = uibutton(app.ExperimentInfoGrid, 'push');
            app.NewAnimalButton.ButtonPushedFcn = createCallbackFcn(app, @NewAnimalButtonPushed, true);
            app.NewAnimalButton.Text = 'New Animal';
            app.NewExperimentButton = uibutton(app.ExperimentInfoGrid, 'push');
            app.NewExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @NewExperimentButtonPushed, true);
            app.NewExperimentButton.Text = 'New Experiment';
            app.RecordingIDLabel = uilabel(app.ExperimentInfoGrid);
            app.RecordingIDLabel.Text = 'RecordingID: ';
            app.TrialNrLabel = uilabel(app.ExperimentInfoGrid);
            app.TrialNrLabel.Text = 'TrialNr:';
            app.RecordingID = uilabel(app.ExperimentInfoGrid);
            app.RecordingID.Text = '[None]';
            app.TrialNr = uilabel(app.ExperimentInfoGrid);
            app.TrialNr.Text = '1';
            app.AnimalIDDropDownLabel = uilabel(app.ExperimentInfoGrid);
            app.AnimalIDDropDownLabel.Text = 'Animal ID';
            app.AnimalID = uidropdown(app.ExperimentInfoGrid);
            app.AnimalID.Items = {'AnimalNum'};
            app.AnimalID.Editable = 'on';
            app.AnimalID.ValueChangedFcn = createCallbackFcn(app, @AnimalIDValueChanged, true);
            app.AnimalID.Value = 'AnimalNum';
            app.ExperimentDropDownLabel = uilabel(app.ExperimentInfoGrid);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentType = uidropdown(app.ExperimentInfoGrid);
            app.ExperimentType.Items = {'ExperimentNum'};
            app.ExperimentType.Editable = 'on';
            app.ExperimentType.ValueChangedFcn = createCallbackFcn(app, @AnimalIDValueChanged, true);
            app.ExperimentType.Value = 'ExperimentNum';
            app.NewTrialButton = uibutton(app.ExperimentInfoGrid, 'push');
            app.NewTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NewTrialButtonPushed, true);
            app.NewTrialButton.Text = 'New Trial';
            app.DAQLabel = uilabel(app.ExperimentInfoGrid);
            app.DAQLabel.Text = 'DAQ:';
            app.DaqName = uidropdown(app.ExperimentInfoGrid);
            app.DaqName.Items = {'none'};
            app.DaqName.ValueChangedFcn = createCallbackFcn(app, @DaqNameValueChanged, true);
            app.DaqName.Value = 'none';
            app.DebugButton = uibutton(app.ExperimentInfoGrid, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = '[DEBUG]';
            app.LoadConfigButton = uibutton(app.ExperimentInfoGrid, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.ConfigFilePath = uieditfield(app.ExperimentInfoGrid, 'text');
            app.DataStoragePanel = uipanel(app.MainPanel);
            app.DataStoragePanel.Title = 'Data Storage';
            app.DataStorageGrid = uigridlayout(app.DataStoragePanel);
            app.PreviousTrials = uilistbox(app.DataStorageGrid);
            app.PreviousTrials.Multiselect = 'on';
            app.PreviousTrials.Value = {'Item 1'};
            app.FilePathTextAreaLabel = uilabel(app.DataStorageGrid);
            app.FilePathTextAreaLabel.Text = 'FilePath';
            app.DataPath = uitextarea(app.DataStorageGrid);
            app.DriveSelectDropDownLabel = uilabel(app.DataStorageGrid);
            app.DriveSelectDropDownLabel.Text = 'Drive Select';
            app.DriveSelect = uidropdown(app.DataStorageGrid);
            app.DriveSelect.Items = {'C:'};
            app.DriveSelect.Value = 'C:';
            app.BaselinesLabel = uilabel(app.DataStorageGrid);
            app.BaselinesLabel.Enable = 'off';
            app.BaselinesLabel.Text = 'Baseline (s)';
            app.BaselineFrames = uieditfield(app.DataStorageGrid, 'numeric');
            app.BaselineFrames.Limits = [0 Inf];
            app.BaselineFrames.Enable = 'off';
            app.BaselineFrames.Value = 2;
            app.PostStimLabel = uilabel(app.DataStorageGrid);
            app.PostStimLabel.Enable = 'off';
            app.PostStimLabel.Text = 'PostStim (s)';
            app.PostStimFrames = uieditfield(app.DataStorageGrid, 'numeric');
            app.PostStimFrames.Limits = [0 Inf];
            app.PostStimFrames.Enable = 'off';
            app.PostStimLabel_2 = uilabel(app.DataStorageGrid);
            app.PostStimLabel_2.Enable = 'off';
            app.PostStimLabel_2.Text = 'Wait (s)';
            app.WaitingTime = uieditfield(app.DataStorageGrid, 'numeric');
            app.WaitingTime.Limits = [0 Inf];
            app.WaitingTime.Enable = 'off';
            app.WaitingTime.Value = 60;
            app.ViewPreviousButton = uibutton(app.DataStorageGrid, 'state');
            app.ViewPreviousButton.ValueChangedFcn = createCallbackFcn(app, @ViewPreviousButtonValueChanged, true);
            app.ViewPreviousButton.Text = 'View Previous Trials';
            app.CameraPropertiesPanel = uipanel(app.MainPanel);
            app.CameraPropertiesPanel.Title = 'Camera Properties';
            app.CameraPropertiesGrid = uigridlayout(app.CameraPropertiesPanel);
            app.ExposureLabel = uilabel(app.CameraPropertiesGrid);
            app.ExposureLabel.Text = 'Exposure';
            app.ExposureTime = uieditfield(app.CameraPropertiesGrid, 'numeric');
            app.ExposureTime.Limits = [21 Inf];
            app.ExposureTime.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeValueChanged, true);
            app.ExposureTime.Value = 30000;
            app.GaindBSpinnerLabel = uilabel(app.CameraPropertiesGrid);
            app.GaindBSpinnerLabel.Text = 'Gain(dB)';
            app.GaindB = uispinner(app.CameraPropertiesGrid);
            app.GaindB.Limits = [0 36];
            app.GaindB.ValueChangedFcn = createCallbackFcn(app, @GaindBValueChanged, true);
            app.CurrentResolution = uilabel(app.CameraPropertiesGrid);
            app.CurrentResolution.Text = '[none]';
            app.CurrentResolutionLabel = uilabel(app.CameraPropertiesGrid);
            app.CurrentResolutionLabel.Text = 'Resolution:';
            app.BinningDropDownLabel = uilabel(app.CameraPropertiesGrid);
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.CameraPropertiesGrid);
            app.BinningDropDown.Items = {'1', '2', '4'};
            app.BinningDropDown.ValueChangedFcn = createCallbackFcn(app, @BinningDropDownValueChanged, true);
            app.BinningDropDown.Value = '4';
            app.SelectCameraROI = uibutton(app.CameraPropertiesGrid, 'push');
            app.SelectCameraROI.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectCameraROI.Text = 'Draw Camera ROI';
            app.ResetCameraROI = uibutton(app.CameraPropertiesGrid, 'push');
            app.ResetCameraROI.ButtonPushedFcn = createCallbackFcn(app, @ResetROIButtonPushed, true);
            app.ResetCameraROI.Text = 'Reset Camera ROI';
            app.CameraROIEditFieldLabel = uilabel(app.CameraPropertiesGrid);
            app.CameraROIEditFieldLabel.Text = 'CameraROI';
            app.CameraROI = uieditfield(app.CameraPropertiesGrid, 'text');
            app.CameraROI.ValueChangedFcn = createCallbackFcn(app, @CameraROIValueChanged, true);
            app.CameraROI.Value = '[default]';
            app.DisplayPanel = uipanel(app.MainPanel);
            app.DisplayPanelGrid = uigridlayout(app.DisplayPanel);
            app.FluorescenceTrace = uiaxes(app.DisplayPanelGrid);
            xlabel(app.FluorescenceTrace, 'Seconds')
            ylabel(app.FluorescenceTrace, 'Fluorescence')
            zlabel(app.FluorescenceTrace, 'Z')
            app.ImagePlot = uiaxes(app.DisplayPanelGrid);
            title(app.ImagePlot, 'Preview')
            app.StatusPanel = uipanel(app.DisplayPanelGrid);
            app.StatusPanel.Title = 'Status';
            app.StatusGrid = uigridlayout(app.StatusPanel);
            app.StimulusDisplay = uiaxes(app.StatusGrid);
            title(app.StimulusDisplay, '[not implemented] stimulus')
            xlabel(app.StimulusDisplay, 'X')
            ylabel(app.StimulusDisplay, 'Y')
            zlabel(app.StimulusDisplay, 'Z')
            app.VerboseModeButton = uibutton(app.StatusGrid, 'state');
            app.VerboseModeButton.ValueChangedFcn = createCallbackFcn(app, @VerboseModeButtonValueChanged, true);
            app.VerboseModeButton.Text = {'Verbose'; 'Mode'};
            app.AcquiredLabel = uilabel(app.StatusGrid);
            app.AcquiredLabel.Text = 'Acquired';
            app.PreviouslyCapturedTime = uilabel(app.StatusGrid);
            app.PreviouslyCapturedTime.Text = '[none]';
            app.ReadyLampLabel = uilabel(app.StatusGrid);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.StatusGrid);
            app.AcquiringLampLabel = uilabel(app.StatusGrid);
            app.AcquiringLampLabel.Text = 'Acquiring';
            app.AcquiringLamp = uilamp(app.StatusGrid);
            app.ResetCameraButton = uibutton(app.StatusGrid, 'push');
            app.ResetCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCameraButtonPushed, true);
            app.ResetCameraButton.Text = 'Reset Camera';
            app.LoggingLabel = uilabel(app.StatusGrid);
            app.LoggingLabel.Text = 'Logging';
            app.LoggingLamp = uilamp(app.StatusGrid);
            app.ReadyTimer = uilabel(app.StatusGrid);
            app.ReadyTimer.Text = '00:00:00';
            app.AcquiringTimer = uilabel(app.StatusGrid);
            app.AcquiringTimer.Text = '00:00:00';
            app.LoggingTimer = uilabel(app.StatusGrid);
            app.LoggingTimer.Text = '00:00:00';
            app.AcquiredCounter = uilabel(app.StatusGrid);
            app.AcquiredCounter.Text = '0/0';
            app.LoadingCompletedLampLabel = uilabel(app.StatusGrid);
            app.LoadingCompletedLampLabel.Text = 'Loading Completed';
            app.LoadingIndicatorLamp = uilamp(app.StatusGrid);
            app.Slider = uislider(app.DisplayPanelGrid);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ControlsPanel = uipanel(app.DisplayPanelGrid);
            app.ControlsPanel.Title = 'Controls';
            app.ControlsGrid = uigridlayout(app.ControlsPanel);
            app.PreviewButton = uibutton(app.ControlsGrid, 'state');
            app.PreviewButton.ValueChangedFcn = createCallbackFcn(app, @PreviewButtonValueChanged, true);
            app.PreviewButton.Text = 'Live Preview';
            app.TakeSnapshotButton = uibutton(app.ControlsGrid, 'push');
            app.TakeSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @TakeSnapshotButtonPushed, true);
            app.TakeSnapshotButton.Text = {'Take'; 'Snapshot'};
            app.SendTrigger = uibutton(app.ControlsGrid, 'push');
            app.SendTrigger.ButtonPushedFcn = createCallbackFcn(app, @SendTriggerButtonPushed, true);
            app.SendTrigger.Text = 'Send Trigger';
            app.LightLabel_2 = uilabel(app.ControlsGrid);
            app.LightLabel_2.Enable = 'off';
            app.LightLabel_2.Text = 'Light';
            app.LightMode = uidropdown(app.ControlsGrid);
            app.LightMode.Items = {'Blue Only', 'Violet Only', 'Alternating'};
            app.LightMode.Enable = 'off';
            app.LightMode.Value = 'Blue Only';
            app.CamModeLabel_2 = uilabel(app.ControlsGrid);
            app.CamModeLabel_2.Text = 'Acquisition';
            app.CameraTriggerMode = uidropdown(app.ControlsGrid);
            app.CameraTriggerMode.Items = {'Hardware Triggered', 'Free Run', 'Software Triggered'};
            app.CameraTriggerMode.ValueChangedFcn = createCallbackFcn(app, @CameraTriggerModeValueChanged, true);
            app.CameraTriggerMode.Value = 'Hardware Triggered';
            app.StartwithStimulusButton = uibutton(app.ControlsGrid, 'push');
            app.StartwithStimulusButton.ButtonPushedFcn = createCallbackFcn(app, @StartwithStimulusButtonPushed, true);
            app.StartwithStimulusButton.Enable = 'off';
            app.StartwithStimulusButton.Text = 'Start with Stimulus';
            app.LastTriggerLabel = uilabel(app.ControlsGrid);
            app.LastTriggerLabel.Text = 'Last Trigger:';
            app.TriggerLastSent = uilabel(app.ControlsGrid);
            app.TriggerLastSent.Text = '[none]';
            app.LoadStimButton = uibutton(app.ControlsGrid, 'push');
            app.LoadStimButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStimButtonPushed, true);
            app.LoadStimButton.Text = 'Load Stim';
            app.StimFilePath = uieditfield(app.ControlsGrid, 'text');
            app.ManualAcquisitionControlPanel = uipanel(app.MainPanel);
            app.ManualAcquisitionControlPanel.Title = 'Manual Acquisition Control';
            app.GridLayout = uigridlayout(app.ManualAcquisitionControlPanel);
            app.TrialTrigger = uibutton(app.GridLayout, 'push');
            app.TrialTrigger.Enable = 'off';
            app.TrialTrigger.Text = {'Trigger'; 'Trial Start'};
            app.StopTrigger = uibutton(app.GridLayout, 'push');
            app.StopTrigger.Enable = 'off';
            app.StopTrigger.Text = {'Stop'; 'Trigger'};
            app.DrawROIButton = uibutton(app.GridLayout, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.DrawROIButton.Text = {'Draw'; 'ROI'};
            app.ResetROIButton = uibutton(app.GridLayout, 'push');
            app.ResetROIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIButtonPushed, true);
            app.ResetROIButton.Text = {'Reset'; 'ROI'};
            app.ModeSelectPanel = uipanel(app.MainPanel);
            app.ModeSelectPanel.Title = 'Mode Select';
            app.ModeSelectGrid = uigridlayout(app.ModeSelectPanel);
            app.CaptureModeDropDownLabel = uilabel(app.ModeSelectGrid);
            app.CaptureModeDropDownLabel.Text = 'Capture Mode';
            app.CaptureMode = uidropdown(app.ModeSelectGrid);
            app.CaptureMode.Items = {'Passive', 'Active'};
            app.CaptureMode.ValueChangedFcn = createCallbackFcn(app, @CaptureModeValueChanged, true);
            app.CaptureMode.Tooltip = {'Passive: the program waits passively to receive images. Acquisition can be triggered manually using the "Send Trigger" button but otherwise the program will not trigger captures.'; 'Active: The program will trigger image capture, await responses, and run stimulus software. '};
            app.CaptureMode.Value = 'Passive';
            app.PassiveControlsPanel = uipanel(app.MainPanel);
            app.PassiveControlsPanel.Title = 'Passive Controls';
            app.PassiveControlsGrid = uigridlayout(app.PassiveControlsPanel);
            app.TogglePassiveAcquisition = uibutton(app.PassiveControlsGrid, 'state');
            app.TogglePassiveAcquisition.ValueChangedFcn = createCallbackFcn(app, @TogglePassiveAcquisitionValueChanged, true);
            app.TogglePassiveAcquisition.Text = 'Start Passive Acquisition';
            app.ImagesperTrialSpinnerLabel = uilabel(app.PassiveControlsGrid);
            app.ImagesperTrialSpinnerLabel.Text = 'Images per Trial';
            app.ImagesPerTrial = uispinner(app.PassiveControlsGrid);
            app.ImagesPerTrial.Limits = [0 Inf];
            app.ImagesPerTrial.ValueChangedFcn = createCallbackFcn(app, @ImagesPerTrialValueChanged, true);
            app.ImagesPerTrial.Tooltip = {'Number of images per trial. Overrides trial timeout. Set value to 0 or Inf to use timeout instead'};
            app.ImagesPerTrial.Value = 100000;
            app.TrialTimeoutsSpinnerLabel = uilabel(app.PassiveControlsGrid);
            app.TrialTimeoutsSpinnerLabel.Tooltip = {'Expected minimum time between trials, in seconds. Used to detect when the trial number should be iterated in image saving. Overridden by number of trials and images per trial.'};
            app.TrialTimeoutsSpinnerLabel.Text = 'Trial Timeout (s)';
            app.TrialTimeout = uispinner(app.PassiveControlsGrid);
            app.TrialTimeout.Step = 0.5;
            app.TrialTimeout.Limits = [0 Inf];
            app.TrialTimeout.ValueChangedFcn = createCallbackFcn(app, @ImagesPerTrialValueChanged, true);
            app.TrialTimeout.Value = 5;
            % Show the figure after all components are created
            app.MainPanel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WidefieldImager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainPanel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainPanel)
        end
    end
end