classdef WidefieldImager_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainPanel                      matlab.ui.Figure
        SnapshotControlsPanel          matlab.ui.container.Panel
        SnapshotControlsGrid           matlab.ui.container.GridLayout
        ImagesPerSnapshot              matlab.ui.control.Spinner
        ImagesperClusterLabel          matlab.ui.control.Label
        StartClusterSnapshot           matlab.ui.control.Button
        PassiveControlsPanel           matlab.ui.container.Panel
        PassiveControlsGrid            matlab.ui.container.GridLayout
        SendTrigger                    matlab.ui.control.Button
        TrialTimeout                   matlab.ui.control.Spinner
        TrialTimeoutsSpinnerLabel      matlab.ui.control.Label
        ImagesPerTrial                 matlab.ui.control.Spinner
        ImagesperTrialSpinnerLabel     matlab.ui.control.Label
        NumTrials                      matlab.ui.control.Spinner
        NumberofTrialsLabel            matlab.ui.control.Label
        StopPassiveAcquisition         matlab.ui.control.StateButton
        StartPassiveAcquisition        matlab.ui.control.StateButton
        MainGrid                       matlab.ui.container.GridLayout
        ModeSelectPanel                matlab.ui.container.Panel
        ModeSelectGrid                 matlab.ui.container.GridLayout
        CaptureMode                    matlab.ui.control.DropDown
        CaptureModeDropDownLabel       matlab.ui.control.Label
        ManualAcquisitionControlPanel  matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        triggerLock                    matlab.ui.control.Button
        StopTrigger                    matlab.ui.control.Button
        StimTrigger                    matlab.ui.control.Button
        TrialTrigger                   matlab.ui.control.Button
        DisplayPanel                   matlab.ui.container.Panel
        DisplayPanelGrid               matlab.ui.container.GridLayout
        Slider                         matlab.ui.control.Slider
        StatusPanel                    matlab.ui.container.Panel
        StatusGrid                     matlab.ui.container.GridLayout
        lockGUI                        matlab.ui.control.StateButton
        CurrentStatus                  matlab.ui.control.StateButton
        AcquisitionStatus              matlab.ui.control.StateButton
        WaitForTrigger                 matlab.ui.control.StateButton
        StimulusDisplay                matlab.ui.control.UIAxes
        ImagePlot                      matlab.ui.control.UIAxes
        FluorescenceTrace              matlab.ui.control.UIAxes
        ImagingInformationPanel        matlab.ui.container.Panel
        ImagingInformationGrid         matlab.ui.container.GridLayout
        CurrentResolutionLabel         matlab.ui.control.Label
        CurrentResolution              matlab.ui.control.Label
        GaindB                         matlab.ui.control.Spinner
        GaindBSpinnerLabel             matlab.ui.control.Label
        ExposureTime                   matlab.ui.control.NumericEditField
        ExposureTimeEditFieldLabel     matlab.ui.control.Label
        DataStoragePanel               matlab.ui.container.Panel
        DataStorageGrid                matlab.ui.container.GridLayout
        WaitingTime                    matlab.ui.control.NumericEditField
        PostStimLabel_2                matlab.ui.control.Label
        PostStimFrames                 matlab.ui.control.NumericEditField
        PostStimLabel                  matlab.ui.control.Label
        BaselineFrames                 matlab.ui.control.NumericEditField
        BaselinesLabel                 matlab.ui.control.Label
        DriveSelect                    matlab.ui.control.DropDown
        DriveSelectDropDownLabel       matlab.ui.control.Label
        BinningDropDown                matlab.ui.control.DropDown
        BinningDropDownLabel           matlab.ui.control.Label
        DataPath                       matlab.ui.control.TextArea
        FilePathTextAreaLabel          matlab.ui.control.Label
        PreviousTrialsListBox          matlab.ui.control.ListBox
        LoadPreviousButton             matlab.ui.control.Button
        ControlsPanel                  matlab.ui.container.Panel
        ControlsGrid                   matlab.ui.container.GridLayout
        ActiveControlsPanel            matlab.ui.container.Panel
        ActiveControlsGrid             matlab.ui.container.GridLayout
        StimulusMode                   matlab.ui.control.DropDown
        StimModeLabel_2                matlab.ui.control.Label
        CameraTriggerMode              matlab.ui.control.DropDown
        CamModeLabel_2                 matlab.ui.control.Label
        LightMode                      matlab.ui.control.DropDown
        LightLabel_2                   matlab.ui.control.Label
        LoadStimButton                 matlab.ui.control.Button
        TakeSnapshotButton             matlab.ui.control.Button
        ResetROIButton                 matlab.ui.control.Button
        SelectROIButton                matlab.ui.control.Button
        StartPreviewButton             matlab.ui.control.StateButton
        ExperimentInformationPanel     matlab.ui.container.Panel
        ExperimentInfoGrid             matlab.ui.container.GridLayout
        ExperimentType                 matlab.ui.control.DropDown
        ExperimentDropDownLabel        matlab.ui.control.Label
        AnimalID                       matlab.ui.control.DropDown
        AnimalIDDropDownLabel          matlab.ui.control.Label
        RecordModeLabel                matlab.ui.control.Label
        TrialNr                        matlab.ui.control.Label
        RecordingID                    matlab.ui.control.Label
        TrialNrLabel                   matlab.ui.control.Label
        RecordingIDLabel               matlab.ui.control.Label
        RecordMode                     matlab.ui.control.DropDown
        NewExperimentButton            matlab.ui.control.Button
        NewAnimalButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        daqName % name of the national instruments DAQ board
        extraFrames % amount of frames where the light is switched off at the end of the recording. This helps to ensure proper alignment with analog data.
        minSize % minimum free disk space before producing a warning (in GB)
        serverPath % data server path
        aNIdevice % analog handle for daq device
        dNIdevice % digital handle for daq device
        vidName % handle for video name
        vidObj % handle for video device
        calibration % Placeholder for calibration mode TODO DO I NEED THIS
        ROIposition % Position of the ROI

        % Path variables - adapted from handles.path which had attributes base, AnimalID, ExpType, RecordingID, 
        pathBase
        pathExpType
        pathAnimalID
        pathRecordingID

        SnapshotTaken % flag to say whether initialisation snapshot has been taken. Recording won't start if it's false
    end
    
    methods (Access = private)
        
        function FixStupidUneditableCode(app)
            %re-assign parents of some UI elements because matlab doesn't
            %let you just do that from the place they're actually
            %initialised because god forbid you trust someone to write code
            %in a coding language >:( 
            % NB the positions of these are just hardcoded in at the
            % moment. I'm wasting time trying to figure out how to do it
            % properly.
            % Remove and do in initialisation if matlab ever gets rid of 
            % that stupid childlock feature
            % panels = {app.SnapshotControlsPanel, app.PassiveControlsPanel};
            % acp = app.ActiveControlsPanel;
            % for i = 1:length(panels)
            %     panl = panels{i};
            %     panl.Parent = acp.Parent;
                % panl.Position = acp.Position;
                % panl.InnerPosition = acp.InnerPosition;
            % end
            % app.SnapshotControlsPanel.Parent = app.ControlsGrid;
            % app.PassiveControlsPanel.Parent = app.ControlsGrid;
            % update visibility
        end

        function vidObj = CheckCamera(app)
            % check videoadapter, set settings from the GUI and start video preview. 
            % Returns an empty object if 'adaptorname' is not available.
            % TODO MAKE THIS CONFIGURABLE
            vidObj = [];
            adaptorName = app.vidName;
            try
                imaqreset
                vidObj = videoinput(adaptorName); %get video object
                src = getselectedsource(vidObj);
                
                if contains(adaptorName, 'pcocameraadaptor') %check for PCO camera and set specific settings
                    clockSpeed = set(src,'PCPixelclock_Hz');
                    [~,idx] = max(str2num(cell2mat(clockSpeed))); %get fastest clockspeed
                    src.PCPixelclock_Hz = clockSpeed{idx}; %fast scanning mode
                    src.E2ExposureTime = 1000/str2double(app.FrameRate.Value) * 1000; %set framerate
                    binFact = 4; %set 4x binning by default
                    
                    try %uses different string in different adaptor versions...
                        src.B1BinningHorizontal = num2str(binFact);
                        src.B2BinningVertical = num2str(binFact);
                    catch
                        src.B1BinningHorizontal = num2str(binFact,'%02i');
                        src.B2BinningVertical = num2str(binFact,'%02i');
                    end
                elseif contains(adaptorName, "gentl")
                    % app.FrameRate.Items = set(src, "AcquisitionFrameRate");
                    % app.FrameRate.Value = src.AcquisitionFrameRate;
                    src.AutoTargetBrightness = 5.019608e-01; %TODO DOES THIS WORK
                    app.ExposureTime.Value = src.ExposureTime;
                    app.GaindB.Value = src.Gain;
                else
                    app.FrameRate.Items = set(src, 'FrameRate');
                    src.FrameRate = app.FrameRate.Value{1}; %use highest available frame rate
                end

                %setup and display live video feed in preview window
                vidRes = get(vidObj,'VideoResolution'); %WHY IS IT 1456 BY 1088 WHAT
                nbands = get(vidObj,'NumberOfBands');
                app.CurrentResolution.Text = [num2str(vidRes(1)) ' x ' num2str(vidRes(2))]; %update current resolution indicator
                %     imshow(zeros(vidRes(2),vidRes(1),nbands),[],'parent',handles.ImagePlot,'XData',[0 1],'YData',[0 1]); %create image object for preview
                % axis(app.ImagePlot, 'image');
                imshow(zeros(vidRes(2),vidRes(1),nbands),[],'parent',app.ImagePlot);%,'InitialMagnification', 'fit'); %create image object for preview
                
                %set default camera configuration
                app.ROIposition = [0 0 vidRes];  %default ROIposition
                set(vidObj,'TriggerFrameDelay',0);
                set(vidObj,'FrameGrabInterval',1);
                set(vidObj,'TriggerRepeat',0);
                set(vidObj,'ROIposition',app.ROIposition);
                set(vidObj,'FramesPerTrigger',Inf);
                triggerconfig(vidObj,'manual');
                
            catch
                vidObj = [];
            end
                        
        end
        
        function app = CheckPath(app)

            % Look for single-letter drives, starting at a: or c: as appropriate
            try
                if ispc
                ret = {};
                for i = double('c') : double('z')
                    if exist(['' i ':' filesep], 'dir') == 7
                        ret{end+1} = [i ':']; %#ok<AGROW>
                    end
                end
            
                elseif ismac
                   ret = dir('/Volumes/');
                   ret = {ret(3:end).name};
                end
                app.DriveSelect.Value = char(ret);
            end

            cPath = java.io.File(strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:))); %make sure this is OS independent
            if (cPath.getFreeSpace / 2^30) < app.minSize && length(app.DriveSelect.Value) > 1 && cPath.getFreeSpace > 0
                answer = questdlg(['Only ' num2str((cPath.getFreeSpace / 2^30)) 'gb left on ' strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:)) filesep '. Change drive?'], ...
                    'Drive select', 'Yes', 'No', 'No, stop asking me', 'Yes');
                if strcmp(answer, 'Yes')
                    checker = true;
                    cDrive = app.DriveSelect.ValueIndex; %keep current drive index
                    while checker
                        app.DriveSelect.ValueIndex = rem(app.DriveSelect.ValueIndex,length(app.DriveSelect.Value))+1; %increase drive selection value by 1
                        cPath = java.io.File(strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:)));
                        if (cPath.getFreeSpace / 2^30) > app.minSize
                            disp(['Changed path to drive ' strtrim(app.DriveSelect.Value(app.DriveSelect.ValueIndex,:)) filesep '. ' num2str((cPath.getFreeSpace / 2^30)) 'gb remaining.'])
                            checker = false;
                        elseif app.DriveSelect.ValueIndex == cDrive
                            disp(['Could not find a drive with more then ' num2str(app.minSize) 'gb of free space. Path unchanged.'])
                            checker = false;
                        end
                    end
                elseif strcmp(answer, 'No, stop asking me')
                    app.minSize = 0; %don't check disk size anymore
                end
            end
            
            % set basepath and look for present animals, experiment types and past recordings
            app.pathBase = [char(cPath) filesep strtrim(app.CameraTriggerMode.Value) filesep]; %set path of imaging code
            
            if ~exist([app.pathBase 'Animals'],'dir') %check for animal path to save data
                mkdir([app.pathBase 'Animals']) %create folder if required
            end

            app.AnimalID.Value = cellstr(app.AnimalID.Value);
            folders = dir([app.pathBase 'Animals']); %find animal folders
            folders = folders([folders.isdir] & ~strncmpi('.', {folders.name}, 1));
            checker = true;
            for iAnimals = 1:size(folders,1) %skip first two entries because they contain folders '.' and '..'
                AllAnimals{iAnimals} = folders(iAnimals).name; %get animal folders
                if checker
                    if strcmp(app.AnimalID.Value,folders(iAnimals).name) %check if current selected animal coincides with discovered folder
                        app.AnimalID.ValueIndex = iAnimals; %keep animal selection constant
                        checker = false;
                    end
                end
            end
            
            if isempty(iAnimals) %Check if any animals are found
                AllAnimals{1} = 'Dummy Subject'; %create dummy animal if nothing else is found
                mkdir([app.pathBase 'Animals' filesep 'Dummy Subject']) %create folder for default experiment
            end
            
            app.AnimalID.Items = AllAnimals; %update AnimalID selection
            if app.AnimalID.ValueIndex > length(AllAnimals)
                app.AnimalID.ValueIndex = 1; %reset indicator
            end
            
            if ~isempty(app.AnimalID.ValueIndex)
                app.pathAnimalID = AllAnimals{app.AnimalID.ValueIndex}; %update path for current animal
            end
            
            folders = dir([app.pathBase 'Animals' filesep AllAnimals{app.AnimalID.ValueIndex}]); %find Experiment folders
            folders = folders([folders.isdir] & ~strncmpi('.', {folders.name}, 1));
            for iExperiments = 1:size(folders,1) %skip first two entries because they contain folders '.' and '..'
                AllExperiments{iExperiments} = folders(iExperiments).name; %get experiment folders
            end
            if isempty(iExperiments) %Check if any experiments are found
                AllExperiments{1} = 'Default'; %create default experiment if nothing else is found
                mkdir([app.pathBase 'Animals' filesep AllAnimals{1} filesep 'Default']) %create folder for default experiment
            end

            app.ExperimentType.Items = AllExperiments; %update experiment type selection
            if size(AllExperiments,2) < app.ExperimentType.ValueIndex 
                app.ExperimentType.ValueIndex = 1; 
            end
            app.pathExpType = AllExperiments{app.ExperimentType.ValueIndex}; %update path for current experiment type
            cPath = [app.pathBase 'Animals' filesep AllAnimals{app.AnimalID.ValueIndex} filesep AllExperiments{app.ExperimentType.ValueIndex}]; %assign current path
            
            date = string(datetime('today'));
            if exist(strcat(cPath, filesep, date), 'dir') && length(dir(strcat(cPath, filesep, date))) > 2 %check if folder for current date exist already and contains data
                Cnt = 1;
                while exist(strcat(cPath, filesep, date, '_', num2str(Cnt)),'dir')
                    Cnt = Cnt +1; %update counter until it is ensured that current experiment name is not used already
                end
                app.pathRecordingID = strcat(date, '_', num2str(Cnt)); %set folder for recording day as the date + neccesarry counter
            else
                app.pathRecordingID = date; %set folder for current recording to recording date
            end
            app.RecordingID.Text = app.pathRecordingID; %update GUI
            app.DataPath.Value = strcat(cPath, filesep, app.pathRecordingID); %set complete file path for data storage
            set(app.TrialNr,'Text','0'); %reset TrialNr
            app.SnapshotTaken = false; %flag for snapshot - has to be taken in order to start data acquisition
            app.CurrentStatus.Text = 'Not ready'; %reset status indicator
            % guidata(app.WidefieldImager, app);
        end
        
        function CheckDAQ(app)
            % https://au.mathworks.com/help/daq/daq.interfaces.dataacquisition.html
            % check NI card
            try
                daqs = daqlist().DeviceInfo;
            catch
                daqs = [];
            end

            if isempty(daqs)
                disp('No NI devices found or data acquisition toolbox missing.')
                disp('Use software triggers instead.')
                app.dNIdevice = [];
            else
                try
                    if isfield(app, 'dNIdevice')
                        delete(app.dNIdevice);
                        delete(app.aNIdevice);
                    end

                    correctIndex = 0;
                    checker = false; %check if daq device with correct ID is present. Default is 'Dev1'
                    for x = 1 : length(daqs)
                        if strcmpi(daqs(x).ID,app.daqName)
                            checker = true;
                            correctIndex = x;
                        end
                    end
                    if ~checker
                        warning(['Could not find specified DAQ: ' app.daqName ' - Using existing board ' daqs(x).ID ' instead.'])
                        app.daqName = daqs(x).ID;
                        correctIndex = x;
                    end
    
                    %Initialise DAQ with correct settings. TODO make sure this is accurate
                    app.dNIdevice = daq(daqs(correctIndex).Vendor.ID); %object for communication with DAQ - digital lines
                    app.aNIdevice = daq(daqs(correctIndex).Vendor.ID); %object for communication with DAQ - analog lines
                    % app.aNIdevice.IsContinuous = true; %set to continous acquisition
                    app.aNIdevice.Rate = 1000; %set sampling rate to 1kHz
                    
                    app.dNIdevice.addoutput(app.daqName, "port1/line4", "Digital");
                    try
                        app.dNIdevice.start();
                    end
                    app.dNIdevice.write(0); %make sure outputs are set to 0 to start.
                    
                    % addDigitalChannel(app.dNIdevice,app.daqName,'port1/line0:2','OutputOnly'); %output channels for blue, violet and mixed light (1.0:blue, 1.1:violet, 1.2:mixed)
                    % outputSingleScan(app.dNIdevice,false(1,3)); %make sure outputs are false
                    % addDigitalChannel(app.dNIdevice,app.daqName,'port0/line0:2','InputOnly'); %input channels to trigger data acquisition and control timing of animal behavior (0.2: trial start, 0.3: stim on)
    
                    % ch = addAnalogInputChannel(app.aNIdevice,app.daqName, 0:3, 'Voltage');
                    % for x = 1 : length(ch)
                    %     ch(x).TerminalConfig = 'SingleEnded'; %use single-ended recordings. Use differential when recording low SNR signals (needs to line for +/-)
                    % end 
                catch
                    disp('Error with setting up NI Device.')
                    disp('Use software triggers instead.')
                    app.dNIdevice = [];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            FixStupidUneditableCode(app)
            app.daqName = "Dev1";
            app.extraFrames = 5;
            app.minSize = 100;
            app.serverPath = '\\your server path';
            
            % app.CameraTriggerMode.Value;
            % Initialise NI card
            CheckDAQ(app);

            %% initialize camera
            info = imaqhwinfo;
            app.vidName = [];
            camCheck = contains(info.InstalledAdaptors, 'pcocameraadaptor');
            
            if any(camCheck)
                app.vidName = info.InstalledAdaptors{camCheck}; %PCO camera
            end
            app.vidObj = CheckCamera(app); %get PCO video object
            
            % check for other cameras if PCO adaptor is unavailable.
            if isempty(app.vidObj)
                disp('PCO camera not available. Searching for other cameras.');
                app.vidName = [];
                if length(info.InstalledAdaptors) == 1
                    app.vidName = info.InstalledAdaptors{1};
                elseif length(info.InstalledAdaptors) > 1 %multiple adaptors present, ask for selection
                    out = listdlg('Name', 'Please select your camera', ...
                        'SelectionMode','single','liststring',info.InstalledAdaptors,'listsize',[300 300]);
                    if ~isempty(out)
                        app.vidName = info.InstalledAdaptors{out};
                    end
                end
                app.BinningDropDown.Enable = 'off'; %this only works with PCO camera so disable for other cameras TODO MAKE IT WORK FOR OTHER CAMERAS
            end
            
            if ~isempty(app.vidName)
                fprintf('Using %s as current video adapter\n',app.vidName);
                app.vidObj = CheckCamera(app); %get non-PCO video object
                
                if ~isempty(app.vidObj)
                    % resolution = app.vidObj.VideoResolution;
                    % app.ImagePlot.YLim = [0 resolution(1)];
                    % app.ImagePlot.XLim = [0 resolution(2)];
                    preview(app.vidObj, app.ImagePlot.Children); %start preview %TODO preview
                    axis(app.ImagePlot, "tight");
                    maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                    cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                    colormap(app.ImagePlot,cMap);
                    CaptureModeValueChanged(app, 0);
                    CameraTriggerModeValueChanged(app, []);
                end
            end
            
            if isempty(app.vidObj)
                warning('No camera found. Type "imaqhwinfo" to make sure your camera is properly installed and running.')
            end

            if any(ismember(app.DriveSelect.Value(:,1), 'g')) %start on G: drive by default
                app.DriveSelect.Value = find(ismember(app.DriveSelect.Value(:,1), 'g'));
            end
            
            % set timer for calibration mode
            app.calibration = [];
            CheckPath(app); %Check for data path, reset date and trialcount
            
            % UIWAIT makes WidefieldImager wait for user response (see UIRESUME)
            % uiwait(handles.WidefieldImager);
        end

        % Value changed function: CameraTriggerMode
        function CameraTriggerModeValueChanged(app, event)
            value = app.CameraTriggerMode.Value;
            % stop(app.vidObj);
            switch value
                case "Hardware Triggered"
                    % app.vidObj.TriggerType = "hardware";
                    app.vidObj.FramesPerTrigger = 1;
                    triggerconfig(app.vidObj, "hardware");
                    pause(3); %avoid race condition??
                    src = getselectedsource(app.vidObj);
                    src.LineSelector = "Line4";
                    src.LineMode = "Input";
                    src.TriggerSelector = "FrameStart";
                    src.TriggerMode = "On";
                    src.TriggerSource = "Line4";
                    src.TriggerActivation = "RisingEdge";
                case "Software Triggered"
                    triggerconfig(app.vidObj, "manual");
                    app.vidObj.FramesPerTrigger = 1;
                    % app.vidObj.TriggerType = "manual";
                case "Free Run"
                    app.vidObj.TriggerType = "immediate";
            end
        end

        % Value changed function: ExposureTime
        function ExposureTimeValueChanged(app, event)
            value = app.ExposureTime.Value;
            src = getselectedsource(app.vidObj);
            src.ExposureTime = value;
        end

        % Value changed function: GaindB
        function GaindBValueChanged(app, event)
            value = app.GaindB.Value;
            src = getselectedsource(app.vidObj);
            src.Gain = value;
        end

        % Button pushed function: TakeSnapshotButton
        function TakeSnapshotButtonPushed(app, event)
            if app.vidObj == 0
                disp('Snapshot not available. Check if camera is connected and restart.')
            else
                h = figure('Toolbar','none','Menubar','none','NumberTitle','off','Name','Snapshot'); %create figure to show snapshot
                snap = getsnapshot(app.vidObj); %get snapshot from video object
                temp = ls(app.pathBase); %check if earlier snapshots exist
                temp(1,8)=' '; % make sure temp has enough characters
                temp = temp(sum(ismember(temp(:,1:8),'Snapshot'),2)==8,:); %only keep snapshot filenames
                temp(~ismember(temp,'0123456789')) = ' '; %replace non-integer characters with blanks
                cNr = max(str2num(temp)); %get highest snapshot nr
                cNr(isempty(cNr)) = 0; %replace empty with 0 if no previous snapshot existed
                save([app.pathBase 'Snapshot_' num2str(cNr+1) '.mat'],'snap') % snapshot
                imwrite(mat2gray(snap),[app.pathBase 'Snapshot_' num2str(cNr+1) '.jpg']) %save snapshot as jpg
            
                %     imshow(snap,'XData',[0 1],'YData',[0 1]); colormap gray; axis image;
                imshow(snap); axis image; title(['Saved as Snapshot ' num2str(cNr+1)]);
                uicontrol('String','Close','Callback','close(gcf)','units','normalized','position',[0 0 0.15 0.07]); %close button
                app.SnapshotTaken = true; %update snapshot flag
                
                % % change status indicator
                % if app.WaitForTrigger.Value %Waiting for trigger and snapshot taken
                %     app.AcqusitionStatus.BackgroundColor = [0 1 0];
                %     app.AcqusitionStatus.String = 'Waiting';
                %     app.CurrentStatus.String = 'Waiting for trigger';
                %     AcquireData(app, app.WidefieldImager); %set to acquisition mode
                % 
                % elseif ~app.WaitForTrigger.Value %Not waiting for trigger but snapshot is taken
                %     app.AcqusitionStatus.Value = false;
                %     app.AcqusitionStatus.BackgroundColor = [1 0 0];
                %     set(app.AcqusitionStatus, 'String' , 'Inactive')
                %     app.CurrentStatus.String = 'Snapshot taken';
                % end
            end
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            if app.vidObj == 0
                disp('ROI change not available. Check if camera is connected and restart.')
            else
                %% select ROI
                closepreview(app.vidObj) %stop camera, and set new ROI position
                stop(app.vidObj) %stop camera
                snap = getsnapshot(app.vidObj);
                imshow(snap,'Parent',app.ImagePlot);
                [~,ROI] = imcrop(app.ImagePlot);
                ROI = floor(ROI);
            
                if ~isempty(ROI)
                    app.CurrentResolution.Value = [num2str(ROI(3)) ' x ' num2str(ROI(4))]; %update current resolution indicator
                    h = figure; %create temporary figure for saving the roi selection
            %         imshow(snap,'XData',[0 1],'YData',[0 1]); hold on %plot current view
                    imshow(snap); hold on %plot current view
                    xVals = [repmat(ROI(1),1,2) repmat(ROI(1)+ROI(3),1,2) ROI(1)];
                    yVals = [ROI(2) repmat(ROI(2)+ROI(4),1,2) repmat(ROI(2),1,2)];
                    plot(xVals,yVals,'r','linewidth',2) %plot ROI outline
                    savefig(gcf,[app.pathBase 'ROI.fig']) %save ROI figure
                    saveas(h,[app.pathBase 'ROI.jpg']) %save ROI as jpg
                    close
            
                    %update preview
                    app.ROIposition = ROI;
                    set(app.vidObj,'ROIposition',app.ROIposition);
                    snap = getsnapshot(app.vidObj); hold(app.ImagePlot,'off');
                    %         imshow(snap,[],'parent',handles.ImagePlot,'XData',[0 1],'YData',[0 1]);
                    imshow(snap,[],'parent',app.ImagePlot);
                end
            
                maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                colormap(app.ImagePlot,cMap);
                preview(app.vidObj,app.ImagePlot.Children); %resume preview
            end
        end

        % Button pushed function: ResetROIButton
        function ResetROIButtonPushed(app, event)
            if app.vidObj == 0
                disp('ROI change not available. Check if camera is connected and restart.')
            else
                vidRes = get(app.vidObj,'VideoResolution');
                app.ROIposition = [0 0 vidRes];  %default ROIposition
                stoppreview(app.vidObj) %stop camera
                stop(app.vidObj) %stop camera
                set(app.vidObj,'ROIposition',app.ROIposition); % set new ROI position
                snap = getsnapshot(app.vidObj); %get snapshot from video object
            %     imshow(snap,[],'parent',handles.ImagePlot,'XData',[0 1],'YData',[0 1]); hold(handles.ImagePlot,'off'); %plot current view
                imshow(snap,[],'parent',app.ImagePlot); hold(app.ImagePlot,'off'); %plot current view
                colormap(app.ImagePlot,'gray');
                preview(app.vidObj,app.ImagePlot.Children); %resume preview
                maxRange = floor(256*0.7); %limit intensity to 70% of dynamic range to avoid ceiling effects
                cMap = gray(maxRange); cMap(end+1:256,:) = repmat([1 0 0 ],256-maxRange,1);
                colormap(app.ImagePlot,cMap);
            
                % update current resolution in GUI
                imHeight = app.ROIposition(3)-app.ROIposition(1);
                imWidth = app.ROIposition(4)-app.ROIposition(2);
                app.CurrentResolution.Value = [num2str(imWidth) ' x ' num2str(imHeight)]; %update current resolution indicator
            end
        end

        % Value changed function: CaptureMode
        function CaptureModeValueChanged(app, event)
            value = app.CaptureMode.Value;
            ControlPanels = {app.ActiveControlsPanel, app.PassiveControlsPanel, app.SnapshotControlsPanel};
            for i = 1:length(ControlPanels)
                panl = ControlPanels{i};
                panl.Enable = "off";
                panl.Visible = "off";
            end
            switch value
                case "Passive Acquisition"
                    app.PassiveControlsPanel.Enable = "on";
                    app.PassiveControlsPanel.Visible = "on";
                    app.vidObj.FramesAcquiredFcnCount = 1;
                    app.vidObj.FramesAcquiredFcn = @receiveFrame;
                case "Active Acquisition and Stim Control"
                    app.ActiveControlsPanel.Enable = "on";
                    app.ActiveControlsPanel.Visible = "on";
                    
                case "Snapshot Mode"
                    app.SnapshotControlsPanel.Enable = "on";
                    app.SnapshotControlsPanel.Visible = "on";
            end
        end

        % Value changed function: StartPassiveAcquisition
        function StartPassiveAcquisitionValueChanged(app, event)
            value = app.StartPassiveAcquisition.Value;
            % TODO https://au.mathworks.com/help/imaq/videoinput.html
            % https://au.mathworks.com/help/parallel-computing/quick-start-parallel-computing-in-matlab.html
        end

        % Callback function
        function CameraTriggerModeValueChanged2(app, event)
            value = app.CameraTriggerMode.Value;
            app.vidObj.TriggerType = value;
        end

        % Button pushed function: SendTrigger
        function SendTriggerButtonPushed(app, event)
            % https://au.mathworks.com/help/daq/daq.interfaces.dataacquisition.html
            % https://au.mathworks.com/help/daq/daq.interfaces.dataacquisition.write.html
            %check vidobj.FramesAcquiredFcn, src input modes
            src = getselectedsource(app.vidObj);
            if ~isrunning(app.vidObj)
                start(app.vidObj);
            end
            switch app.CameraTriggerMode.Value
                case "Hardware Triggered"
                    write(app.dNIdevice, 1);
                    pause(1)
                    % pause(app.ExposureTime / 1000000);
                    write(app.dNIdevice, 0);
                    disp("trigger sent")
                case "Software Triggered"
                    trigger(app.vidObj)
                    disp("camera triggered")
                case "Free Run"
                    %what are you doing here.
                    disp("TODO grey out this button when the trigger mode is auto");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainPanel = uifigure('Visible', 'off');
            app.MainPanel.Name = 'MATLAB App';
            app.MainGrid = uigridlayout(app.MainPanel);
            app.ExperimentInformationPanel = uipanel(app.MainGrid);
            app.ExperimentInformationPanel.Title = 'Experiment Information';
            app.ExperimentInfoGrid = uigridlayout(app.ExperimentInformationPanel);
            app.NewAnimalButton = uibutton(app.ExperimentInfoGrid, 'push');
            app.NewAnimalButton.Text = 'New Animal';
            app.NewExperimentButton = uibutton(app.ExperimentInfoGrid, 'push');
            app.NewExperimentButton.Text = 'New Experiment';
            app.RecordMode = uidropdown(app.ExperimentInfoGrid);
            app.RecordMode.Items = {'Mapping', 'Behavioural'};
            app.RecordMode.Value = 'Mapping';
            app.RecordingIDLabel = uilabel(app.ExperimentInfoGrid);
            app.RecordingIDLabel.Text = 'RecordingID: ';
            app.TrialNrLabel = uilabel(app.ExperimentInfoGrid);
            app.TrialNrLabel.Text = 'TrialNr:';
            app.RecordingID = uilabel(app.ExperimentInfoGrid);
            app.RecordingID.Text = '[None]';
            app.TrialNr = uilabel(app.ExperimentInfoGrid);
            app.TrialNr.Text = '[None]';
            app.RecordModeLabel = uilabel(app.ExperimentInfoGrid);
            app.RecordModeLabel.Text = 'Record Mode';
            app.AnimalIDDropDownLabel = uilabel(app.ExperimentInfoGrid);
            app.AnimalIDDropDownLabel.Text = 'Animal ID';
            app.AnimalID = uidropdown(app.ExperimentInfoGrid);
            app.AnimalID.Items = {'AnimalNum'};
            app.AnimalID.Value = 'AnimalNum';
            app.ExperimentDropDownLabel = uilabel(app.ExperimentInfoGrid);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentType = uidropdown(app.ExperimentInfoGrid);
            app.ExperimentType.Items = {'ExperimentNum'};
            app.ExperimentType.Value = 'ExperimentNum';
            app.ControlsPanel = uipanel(app.MainGrid);
            app.ControlsPanel.Title = 'Controls';
            app.ControlsGrid = uigridlayout(app.ControlsPanel);
            app.StartPreviewButton = uibutton(app.ControlsGrid, 'state');
            app.StartPreviewButton.Text = {'Start'; 'Preview'};
            app.SelectROIButton = uibutton(app.ControlsGrid, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = {'Select'; 'ROI'};
            app.ResetROIButton = uibutton(app.ControlsGrid, 'push');
            app.ResetROIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIButtonPushed, true);
            app.ResetROIButton.Text = {'Reset'; 'ROI'};
            app.TakeSnapshotButton = uibutton(app.ControlsGrid, 'push');
            app.TakeSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @TakeSnapshotButtonPushed, true);
            app.TakeSnapshotButton.Text = {'Take'; 'Snapshot'};
            app.ActiveControlsPanel = uipanel(app.ControlsGrid);
            app.ActiveControlsGrid = uigridlayout(app.ActiveControlsPanel);
            app.LoadStimButton = uibutton(app.ActiveControlsGrid, 'push');
            app.LoadStimButton.Text = {'Load Stim'; ''};
            app.LightLabel_2 = uilabel(app.ActiveControlsGrid);
            app.LightLabel_2.Text = 'Light';
            app.LightMode = uidropdown(app.ActiveControlsGrid);
            app.LightMode.Items = {'Blue Only', 'Violet Only', 'Alternating'};
            app.LightMode.Value = 'Blue Only';
            app.CamModeLabel_2 = uilabel(app.ActiveControlsGrid);
            app.CamModeLabel_2.Text = 'Acquisition';
            app.CameraTriggerMode = uidropdown(app.ActiveControlsGrid);
            app.CameraTriggerMode.Items = {'Hardware Triggered', 'Free Run', 'Software Triggered'};
            app.CameraTriggerMode.ValueChangedFcn = createCallbackFcn(app, @CameraTriggerModeValueChanged, true);
            app.CameraTriggerMode.Value = 'Hardware Triggered';
            app.StimModeLabel_2 = uilabel(app.ActiveControlsGrid);
            app.StimModeLabel_2.Text = 'Stimulus';
            app.StimulusMode = uidropdown(app.ActiveControlsGrid);
            app.StimulusMode.Items = {'None', 'Pre-Loaded', 'Ad-Hoc'};
            app.StimulusMode.Value = 'None';
            app.DataStoragePanel = uipanel(app.MainGrid);
            app.DataStoragePanel.Title = 'Data Storage';
            app.DataStorageGrid = uigridlayout(app.DataStoragePanel);
            app.LoadPreviousButton = uibutton(app.DataStorageGrid, 'push');
            app.LoadPreviousButton.Text = 'Load Previous';
            app.PreviousTrialsListBox = uilistbox(app.DataStorageGrid);
            app.FilePathTextAreaLabel = uilabel(app.DataStorageGrid);
            app.FilePathTextAreaLabel.Text = 'FilePath';
            app.DataPath = uitextarea(app.DataStorageGrid);
            app.BinningDropDownLabel = uilabel(app.DataStorageGrid);
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.DataStorageGrid);
            app.BinningDropDown.Items = {'1', '2', '4'};
            app.BinningDropDown.Value = '1';
            app.DriveSelectDropDownLabel = uilabel(app.DataStorageGrid);
            app.DriveSelectDropDownLabel.Text = 'Drive Select';
            app.DriveSelect = uidropdown(app.DataStorageGrid);
            app.DriveSelect.Items = {'C:\'};
            app.DriveSelect.Value = 'C:\';
            app.BaselinesLabel = uilabel(app.DataStorageGrid);
            app.BaselinesLabel.Text = 'Baseline (s)';
            app.BaselineFrames = uieditfield(app.DataStorageGrid, 'numeric');
            app.BaselineFrames.Limits = [0 Inf];
            app.BaselineFrames.Value = 2;
            app.PostStimLabel = uilabel(app.DataStorageGrid);
            app.PostStimLabel.Text = 'PostStim (s)';
            app.PostStimFrames = uieditfield(app.DataStorageGrid, 'numeric');
            app.PostStimFrames.Limits = [0 Inf];
            app.PostStimLabel_2 = uilabel(app.DataStorageGrid);
            app.PostStimLabel_2.Text = 'Wait (s)';
            app.WaitingTime = uieditfield(app.DataStorageGrid, 'numeric');
            app.WaitingTime.Value = 60;
            app.ImagingInformationPanel = uipanel(app.MainGrid);
            app.ImagingInformationPanel.Title = 'Imaging Information';
            app.ImagingInformationGrid = uigridlayout(app.ImagingInformationPanel);
            app.ExposureTimeEditFieldLabel = uilabel(app.ImagingInformationGrid);
            app.ExposureTimeEditFieldLabel.Text = 'ExposureTime ';
            app.ExposureTime = uieditfield(app.ImagingInformationGrid, 'numeric');
            app.ExposureTime.Limits = [0 Inf];
            app.ExposureTime.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeValueChanged, true);
            app.GaindBSpinnerLabel = uilabel(app.ImagingInformationGrid);
            app.GaindBSpinnerLabel.Text = 'Gain(dB)';
            app.GaindB = uispinner(app.ImagingInformationGrid);
            app.GaindB.Limits = [0 Inf];
            app.GaindB.ValueChangedFcn = createCallbackFcn(app, @GaindBValueChanged, true);
            app.CurrentResolution = uilabel(app.ImagingInformationGrid);
            app.CurrentResolution.Text = '[None]';
            app.CurrentResolutionLabel = uilabel(app.ImagingInformationGrid);
            app.CurrentResolutionLabel.Text = 'Resolution';
            app.DisplayPanel = uipanel(app.MainGrid);
            app.DisplayPanelGrid = uigridlayout(app.DisplayPanel);
            app.FluorescenceTrace = uiaxes(app.DisplayPanelGrid);
            title(app.FluorescenceTrace, 'Fluorescence Trace')
            xlabel(app.FluorescenceTrace, 'X')
            ylabel(app.FluorescenceTrace, 'Y')
            zlabel(app.FluorescenceTrace, 'Z')
            app.ImagePlot = uiaxes(app.DisplayPanelGrid);
            title(app.ImagePlot, 'Preview')
            app.StatusPanel = uipanel(app.DisplayPanelGrid);
            app.StatusPanel.Title = 'Recording Status';
            app.StatusGrid = uigridlayout(app.StatusPanel);
            app.StimulusDisplay = uiaxes(app.StatusGrid);
            title(app.StimulusDisplay, 'Stimulus')
            xlabel(app.StimulusDisplay, 'X')
            ylabel(app.StimulusDisplay, 'Y')
            zlabel(app.StimulusDisplay, 'Z')
            app.WaitForTrigger = uibutton(app.StatusGrid, 'state');
            app.WaitForTrigger.Text = 'WaitForTrigger';
            app.AcquisitionStatus = uibutton(app.StatusGrid, 'state');
            app.AcquisitionStatus.Text = 'AcquisitionStatus';
            app.CurrentStatus = uibutton(app.StatusGrid, 'state');
            app.CurrentStatus.Text = 'CurrentStatus';
            app.lockGUI = uibutton(app.StatusGrid, 'state');
            app.lockGUI.Text = 'lockGUI';
            app.Slider = uislider(app.DisplayPanelGrid);
            app.ManualAcquisitionControlPanel = uipanel(app.MainGrid);
            app.ManualAcquisitionControlPanel.Title = 'Manual Acquisition Control';
            app.GridLayout = uigridlayout(app.ManualAcquisitionControlPanel);
            app.TrialTrigger = uibutton(app.GridLayout, 'push');
            app.TrialTrigger.Text = {'Trigger'; 'Trial Start'};
            app.StimTrigger = uibutton(app.GridLayout, 'push');
            app.StimTrigger.Text = {'Trigger '; 'Stimulus'};
            app.StopTrigger = uibutton(app.GridLayout, 'push');
            app.StopTrigger.Text = {'Stop'; 'Trigger'};
            app.triggerLock = uibutton(app.GridLayout, 'push');
            app.triggerLock.Text = {'Lock'; 'Triggers'};
            app.ModeSelectPanel = uipanel(app.MainGrid);
            app.ModeSelectPanel.Title = 'Mode Select';
            app.ModeSelectGrid = uigridlayout(app.ModeSelectPanel);
            app.CaptureModeDropDownLabel = uilabel(app.ModeSelectGrid);
            app.CaptureModeDropDownLabel.Text = 'Capture Mode';
            app.CaptureMode = uidropdown(app.ModeSelectGrid);
            app.CaptureMode.Items = {'Passive Acquisition', 'Active Acquisition and Stim Control', 'Snapshot Mode'};
            app.CaptureMode.ValueChangedFcn = createCallbackFcn(app, @CaptureModeValueChanged, true);
            app.CaptureMode.Value = 'Passive Acquisition';
            app.PassiveControlsPanel = uipanel(app.MainPanel);
            app.PassiveControlsGrid = uigridlayout(app.PassiveControlsPanel);
            app.StartPassiveAcquisition = uibutton(app.PassiveControlsGrid, 'state');
            app.StartPassiveAcquisition.ValueChangedFcn = createCallbackFcn(app, @StartPassiveAcquisitionValueChanged, true);
            app.StartPassiveAcquisition.Text = 'Start';
            app.StopPassiveAcquisition = uibutton(app.PassiveControlsGrid, 'state');
            app.StopPassiveAcquisition.Text = 'Stop';
            app.NumberofTrialsLabel = uilabel(app.PassiveControlsGrid);
            app.NumberofTrialsLabel.Tooltip = {'Expected number of trials in the imaging session. Overrides trial timeout. Set value to 0 or Inf to use timeout instead'};
            app.NumberofTrialsLabel.Text = 'Number of Trials';
            app.NumTrials = uispinner(app.PassiveControlsGrid);
            app.NumTrials.Limits = [0 Inf];
            app.NumTrials.Value = Inf;
            app.ImagesperTrialSpinnerLabel = uilabel(app.PassiveControlsGrid);
            app.ImagesperTrialSpinnerLabel.Text = 'Images per Trial';
            app.ImagesPerTrial = uispinner(app.PassiveControlsGrid);
            app.ImagesPerTrial.Limits = [0 Inf];
            app.ImagesPerTrial.Tooltip = {'Number of images per trial. Overrides trial timeout. Set value to 0 or Inf to use timeout instead'};
            app.ImagesPerTrial.Value = Inf;
            app.TrialTimeoutsSpinnerLabel = uilabel(app.PassiveControlsGrid);
            app.TrialTimeoutsSpinnerLabel.Tooltip = {'Expected minimum time between trials, in seconds. Used to detect when the trial number should be iterated in image saving. Overridden by number of trials and images per trial.'};
            app.TrialTimeoutsSpinnerLabel.Text = 'Trial Timeout (s)';
            app.TrialTimeout = uispinner(app.PassiveControlsGrid);
            app.TrialTimeout.Step = 0.5;
            app.TrialTimeout.Limits = [0 Inf];
            app.TrialTimeout.Value = 5;
            app.SendTrigger = uibutton(app.PassiveControlsGrid, 'push');
            app.SendTrigger.ButtonPushedFcn = createCallbackFcn(app, @SendTriggerButtonPushed, true);
            app.SendTrigger.Text = 'Send Trigger';
            app.SnapshotControlsPanel = uipanel(app.MainPanel);
            app.SnapshotControlsGrid = uigridlayout(app.SnapshotControlsPanel);
            app.StartClusterSnapshot = uibutton(app.SnapshotControlsGrid, 'push');
            app.StartClusterSnapshot.Text = 'Cluster Snapshot';
            app.ImagesperClusterLabel = uilabel(app.SnapshotControlsGrid);
            app.ImagesperClusterLabel.Text = 'Images per Cluster';
            app.ImagesPerSnapshot = uispinner(app.SnapshotControlsGrid);
            app.ImagesPerSnapshot.Limits = [1 1000000];
            app.ImagesPerSnapshot.Tooltip = {'Images to take per cluster snapshot. Set framerate in "imaging information" panel. To take a single image snapshot, select "take snapshot" or set Images per Cluster to 1.'};
            app.ImagesPerSnapshot.Value = 1;
            % Show the figure after all components are created
            app.MainPanel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WidefieldImager_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainPanel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainPanel)
        end
    end
end